classdef Practica2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        lbltitulo                       matlab.ui.control.Label
        TipodegraficaDropDownLabel      matlab.ui.control.Label
        lisTipo                         matlab.ui.control.DropDown
        btngrafico1                     matlab.ui.control.Button
        TiempodelagraficaDropDownLabel  matlab.ui.control.Label
        listiempo                       matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        edit1                           matlab.ui.control.NumericEditField
        edit2                           matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        edit3                           matlab.ui.control.NumericEditField
        btngrafico2                     matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        edit4                           matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        edit5                           matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        edit6                           matlab.ui.control.NumericEditField
        PartedelagraficaDropDownLabel   matlab.ui.control.Label
        opciografica                    matlab.ui.control.DropDown
        Label_6                         matlab.ui.control.Label
        FuncionoriginallLabel           matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        cbx1                            matlab.ui.control.CheckBox
        cbx2                            matlab.ui.control.CheckBox
        Label_14                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        cbx4                            matlab.ui.control.CheckBox
        cbx3                            matlab.ui.control.CheckBox
        Label_16                        matlab.ui.control.Label
        SumadeXoyXeLabel                matlab.ui.control.Label
        EnergiaLampLabel                matlab.ui.control.Label
        Lamp1                           matlab.ui.control.Lamp
        PotencialLampLabel              matlab.ui.control.Label
        Lamp2                           matlab.ui.control.Lamp
        NingunaLampLabel                matlab.ui.control.Label
        Lamp3                           matlab.ui.control.Lamp
        Label_17                        matlab.ui.control.Label
        TipodesealSwitchLabel           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        OperacionesBsicasSobrelaSealDropDownLabel  matlab.ui.control.Label
        operacion                       matlab.ui.control.DropDown
        Label_18                        matlab.ui.control.Label
        FunciondelaoperacionLabel       matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        edit7                           matlab.ui.control.NumericEditField
        bLabel                          matlab.ui.control.Label
        edit8                           matlab.ui.control.NumericEditField
        dLabel                          matlab.ui.control.Label
        edit9                           matlab.ui.control.NumericEditField
        Label_19                        matlab.ui.control.Label
        FuncionExtraLabel               matlab.ui.control.Label
        TipodegraficaDropDownLabel_2    matlab.ui.control.Label
        lisTipo_2                       matlab.ui.control.DropDown
        eLabel                          matlab.ui.control.Label
        edit10                          matlab.ui.control.NumericEditField
        fLabel                          matlab.ui.control.Label
        edit11                          matlab.ui.control.NumericEditField
        PartedelagraficaDropDownLabel_2  matlab.ui.control.Label
        opciografica_2                  matlab.ui.control.DropDown
        Grafico                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
       function crossCorrelation =calcularCorrelacionCruzada(x, y)
            M = length(x); % Longitud de la señal x
            N = length(y); % Longitud de la señal y
            correlation = zeros(1, M + N - 1); % Inicializar la correlación cruzada con ceros
            
            % Calcular la correlación cruzada para cada desplazamiento l
            for l = -(N-1) : M
                r = 0; % Variable para almacenar el valor de correlación cruzada para el desplazamiento l
                
                for n = max(1, -l) : min(M, M-l)
                    r = r + x(n) * y(n + l);
                end
                
                correlation(l + N) = r;
            end
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: lisTipo
        function lisTipoValueChanged(app, event)

            val=app.lisTipo.Value; %get
            switch val
                case 'Seleciopnar opcion'
                    app.lisTipo_2.Visible='off';
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'off';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.FrecuenciadeMuestreoEditFieldLabel.Visible = 'off';
                    app.edit6.Visible= 'off';
                    app.Label_5.Visible= 'off';
                    app.edit5.Visible= 'off';
                    app.edit1.Enable = 'off';
                    app.edit2.Enable = 'off';
                    app.edit3.Enable = 'off';
                    app.Label_3.Visible = 'off';
                    app.Label_2.Visible = 'off';
                    app.Label_4.Visible= 'off';
                    app.edit4.Visible= 'off';
                    app.Label.Visible = 'off';
                    app.edit1.Visible = 'off';
                    app.edit2.Visible = 'off';
                    app.edit3.Visible = 'off';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Señal Cuadrada'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'}; 
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'on';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.Label_4.Visible= 'on';
                    app.edit4.Visible= 'on';
                    app.Label_5.Visible= 'on';
                    app.edit5.Visible= 'on';
                    app.edit1.Enable = 'on';
                    app.edit2.Enable = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.Label_2.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='amplitud';
                    app.Label_2.Text = 'Frecuencias';
                    app.Label_3.Text= 'Cliclo';
                    app.Label_4.Text= 'Tiempo de MAX';
                    app.Label_5.Text= 'Tiempo de Min';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Diente de Sierra'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'}; 
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'on';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.Label_5.Visible= 'on';
                    app.edit5.Visible= 'on';
                    app.Label_4.Visible= 'on';
                    app.edit4.Visible= 'on';
                    app.edit1.Enable = 'on';
                    app.edit2.Enable = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.Label_2.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='Amplitud';
                    app.Label_2.Text = 'Frecuencia';
                    app.Label_3.Text= 'Ancho';
                    app.Label_4.Text= 'Tiempo de MAX';
                    app.Label_5.Text= 'Tiempo de Min';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Señal Senoidal'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'}; 
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'on';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.Label_5.Visible= 'on';
                    app.edit5.Visible= 'on';
                    app.Label_4.Visible= 'on';
                    app.edit4.Visible= 'on';
                    app.edit1.Enable = 'on';
                    app.edit2.Enable = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.Label_2.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='Amplitud';
                    app.Label_2.Text = 'Frecuencia';
                    app.Label_3.Text= 'Desfase';
                    app.Label_4.Text= 'Tiempo de MAX';
                    app.Label_5.Text= 'Tiempo de Min';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Exponencial Crecientey Decreciente'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'}; 
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'off';
                    app.UIAxes.Visible= 'on';
                    app.UIAxes_2.Visible= 'on';
                    app.Label_4.Visible= 'off';
                    app.edit4.Visible= 'off';
                    app.Label_5.Visible= 'off';
                    app.edit5.Visible= 'off';
                    app.edit1.Enable = 'on';
                    app.edit2.Enable = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.Label_2.Visible = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='Base';
                    app.Label_2.Text = 'min Tiempo';
                    app.Label_3.Text= 'max Tiempo';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case'Señal Exponencial Compleja'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'};
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    app.opciografica.Items = {'Seleciopnar opcion', 'Cálculo de la parte Real de x(t)', 'Cálculo de la parte imaginaria de x(t)', 'Cálculo de la magnitud de x(t)','Cálculo del Argumento de x(t)'}; 
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'on';
                    app.Grafico.Visible= 'on';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.Label_4.Visible= 'off';
                    app.edit4.Visible= 'off';
                    app.Label_5.Visible= 'off';
                    app.edit5.Visible= 'off';
                    app.edit1.Enable = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Enable = 'on';
                    app.Label_2.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Enable = 'off';
                    app.Label_3.Visible = 'off';
                    app.edit3.Visible = 'off';
                    app.Label.Text = 'min Tiempo';
                    app.Label_2.Text = 'max Tiempo*pi';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Generación de una señal Escalón'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'};
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    app.opciografica.Items = {'Seleciopnar opcion', 'Escalon con heaviside','Escalon con ones','Escalon con ADSP'}; 
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'on';
                    app.Grafico.Visible= 'on';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.Label_4.Visible= 'off';
                    app.edit4.Visible= 'off';
                    app.Label_5.Visible= 'off';
                    app.edit5.Visible= 'off';
                    app.edit1.Enable = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Enable = 'on';
                    app.Label_2.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='Amplitud';
                    app.Label_2.Text = 'min Tiempo';
                    app.Label_3.Text = 'max Tiempo';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Generación de una señal Impulso'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'};
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'off';
                    app.UIAxes.Visible= 'on';
                    app.UIAxes_2.Visible= 'on';
                    app.Label_4.Visible= 'off';
                    app.edit4.Visible= 'off';
                    app.Label_5.Visible= 'off';
                    app.edit5.Visible= 'off';
                    app.edit1.Enable = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Enable = 'on';
                    app.Label_2.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='Amplitud';
                    app.Label_2.Text = 'min Tiempo';
                    app.Label_3.Text = 'max Tiempo';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Generación de una señal Rampa'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'};
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'on';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.Label_4.Visible= 'off';
                    app.edit4.Visible= 'off';
                    app.Label_5.Visible= 'off';
                    app.edit5.Visible= 'off';
                    app.edit1.Enable = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Enable = 'on';
                    app.Label_2.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='Amplitud';
                    app.Label_2.Text = 'min Tiempo';
                    app.Label_3.Text = 'max Tiempo';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Generación de una señal Rectangular'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'};
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'on';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.Label_4.Visible= 'off';
                    app.edit4.Visible= 'off';
                    app.Label_5.Visible= 'off';
                    app.edit5.Visible= 'off';
                    app.edit1.Enable = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Enable = 'on';
                    app.Label_2.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='Amplitud';
                    app.Label_2.Text = 'min Tiempo';
                    app.Label_3.Text = 'max Tiempo';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Generación de una señal Triangular'
                    app.lisTipo_2.Visible='off';
                    app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'};
                    app.Lamp1.Enable = 'off';
                    app.Lamp2.Enable = 'off';
                    app.Lamp3.Enable = 'off';
                    cla(app.UIAxes,'reset');
                    cla(app.Grafico,'reset');
                    cla(app.UIAxes_2,'reset');
                    app.edit1.Value = 0;
                    app.edit2.Value = 0;
                    app.edit3.Value = 0;
                    app.edit4.Value = 0;
                    app.edit5.Value = 0;
                    app.edit6.Value = 10000;
                    app.opciografica.Visible= 'off';
                    app.Grafico.Visible= 'on';
                    app.UIAxes.Visible= 'off';
                    app.UIAxes_2.Visible= 'off';
                    app.Label_4.Visible= 'off';
                    app.edit4.Visible= 'off';
                    app.Label_5.Visible= 'off';
                    app.edit5.Visible= 'off';
                    app.edit1.Enable = 'on';
                    app.Label.Visible = 'on';
                    app.edit1.Visible = 'on';
                    app.edit2.Enable = 'on';
                    app.Label_2.Visible = 'on';
                    app.edit2.Visible = 'on';
                    app.edit3.Enable = 'on';
                    app.Label_3.Visible = 'on';
                    app.edit3.Visible = 'on';
                    app.Label.Text='Amplitud';
                    app.Label_2.Text = 'min Tiempo';
                    app.Label_3.Text = 'max Tiempo';
                    app.operacion.Value = 'Selecionar opcion';
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                otherwise
            end
                
            
        end

        % Button pushed function: btngrafico1
        function btngrafico1Pushed(app, event)
            app.Lamp1.Enable = 'off';
            app.Lamp2.Enable = 'off';
            app.Lamp3.Enable = 'off';
            val=app.lisTipo.Value; %get
            peri=app.Switch.Value;
            val2=app.lisTipo_2.Value;
            grap3=0;
            Fs = app.edit6.Value;
            switch val2
                case 'Señal Senoidal'
                        A2=app.edit7.Value;
                        f = app.edit8.Value;
                        phi = app.edit9.Value;
                        a=app.edit11.Value;
                        b=app.edit10.Value;
                        switch peri
                            case 'Periodica'
                                t=a:1/Fs:b-1/Fs;
                            case 'Aperiodica'
                                T=1/f;
                                t=a:1/Fs:T-1/Fs;
                        end
                        grap2 =A2*sin(2*pi*f*T+phi);
                case 'Señal Exponencial Compleja'
                        a=app.edit8.Value;
                        b=app.edit7.Value;
                        T=a*pi:1/Fs:b*pi;
                        x3 = exp(1j.*(2.*T+(pi/4)));
                        opci3=app.opciografica_2.Value;
                        switch opci3
                            case 'Cálculo de la parte Real de x(t)'
                                grap2=real(x3);
                            case 'Cálculo de la parte imaginaria de x(t)'
                                grap2=imag(x3);
                            case 'Cálculo de la magnitud de x(t)'
                                grap2=abs(x3);
                            case 'Cálculo del Argumento de x(t)'
                                grap2=atan(imag(x3)./real(x3));
                        end
                case 'Señal Cuadrada'                        
                        A2= app.edit7.Value; 
                        f=app.edit8.Value;
                        duty= app.edit9.Value;
                        a=app.edit11.Value;
                        b=app.edit10.Value;
                        switch peri
                            case 'Periodica'
                                t=a:1/Fs:b-1/Fs;
                            case 'Aperiodica'
                                T=1/f;
                                t=a:1/Fs:T-1/Fs;
                        end 
                        grap2 =A2.*square(2*pi*f*t,duty);
                case 'Diente de Sierra'                        
                        A2= app.edit7.Value;
                        f2= app.edit8.Value;
                        width2=app.edit9.Value;
                        a=app.edit11.Value;
                        b=app.edit10.Value;
                        switch peri
                            case 'Periodica'
                                t=a:1/Fs:b-1/Fs;
                            case 'Aperiodica'
                                T=1/f2;
                                t=a:1/Fs:T-1/Fs;
                        end
                        grap2 =A2*sawtooth(2*pi*f2*T,width2);
                case 'Exponencial Crecientey Decreciente'
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        T=a:1/Fs:b;
                        grap2=exp(T);
                        r= app.edit7.Value;
                        grap3 = r.^(-T);
                case 'Generación de una señal Rampa'
                        A2=app.edit7.Value;
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        T = a:1/Fs:(b/2);
                        r=T;
                        grap2 = A2.*[zeros(1,length(T)),r];
                case 'Generación de una señal Escalón'
                        A2=app.edit7.Value;
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        T = a:1/Fs:b;
                        grap2 = A2*heaviside(T);
                case 'Generación de una señal Impulso'
                    A2=app.edit7.Value;
                    a=app.edit9.Value;
                    b=app.edit8.Value;
                    n2=a:b;
                    grap2 = A2.*[zeros(1,3),ones(1,1),zeros(1,2)];
                    grap3=(n2-3)==0;
                case 'Generación de una señal Triangular'
                        A2=app.edit7.Value;
                         if (app.edit4.Visible=='on')
                            a=app.edit5.Value;
                            b=app.edit4.Value;
                        else
                            a=app.edit2.Value;
                            b=app.edit3.Value;
                        end
                        T=a:1/Fs:b;
                        grap2 = A2.*tripuls(T);
                case 'Generación de una señal Rectangular'
                        A2=app.edit7.Value;
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        T=a:1/Fs:b;
                        grap2 = A2.*rectpuls(T);
            end
            switch val
                case 'Seleciopnar opcion'
                case 'Señal Cuadrada'
                    A= app.edit1.Value; 
                    f=app.edit2.Value;
                    duty= app.edit3.Value;
                    %selecion de tiempo
                    switch peri
                            case 'Periodica'
                                t=app.edit5.Value:1/Fs:app.edit4.Value-1/Fs;
                                t_1=-app.edit4.Value:1/Fs:-app.edit5.Value-1/Fs;
                            case 'Aperiodica'
                                T=1/f;
                                t=app.edit5.Value:1/Fs:T-1/Fs;
                                t_1=-T:1/Fs:-app.edit5.Value-1/Fs;
                    end
                    %operacion
                    x=A.*square(2*pi*f*t,duty);
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    plot(app.Grafico,t,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        plot(app.Grafico,t_1,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        plot(app.Grafico,t_1,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        plot(app.Grafico,t,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        plot(app.Grafico,t,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.Grafico,t,xa,'cyan');
                    end
                    Ex = sum(x.^2);
                    if(Ex>0)
                        app.Lamp1.Enable='on';
                    end
                    Ex = trapz(abs(x).^2,t);
                    Px = (1/(t(end)-t(1)))*trapz(abs(x).^2,t);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                plot(app.Grafico,t,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Multiplicación '
                                xs=x.*grap2;
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x);
                                %t(:,1:length(yt))
                                plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                syms S
                                f1= x;
                                f = @(S) f1;
                                int= integral(f,app.edit5.Value,app.edit4.Value);
                                plot(app.Grafico,t,int,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A.*square(2*pi*f*m*t,duty);
                                plot(app.Grafico,t,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A.*square(2*pi*f*(t+re),duty);
                                plot(app.Grafico,t+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2,'same');
                                t_conv = linspace(2*t(1), 2*t(end), length(xs));
                                plot(app.Grafico,t,x,'red');
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                plot(app.Grafico,x,'red');
                                plot(app.Grafico,xs,'blue');
                                plot(app.Grafico,grap2,'magenta');
                        end
                    end
                case 'Diente de Sierra'
                    A= app.edit1.Value;
                    f= app.edit2.Value;
                    width=app.edit3.Value;
                    %tiempo
                    switch peri
                            case 'Periodica'
                                t=app.edit5.Value:1/Fs:app.edit4.Value-1/Fs;
                                t_1=-app.edit4.Value:1/Fs:-app.edit5.Value-1/Fs;
                            case 'Aperiodica'
                                T=1/f;
                                t=app.edit5.Value:1/Fs:T-1/Fs;
                                t_1=-T:1/Fs:-app.edit5.Value-1/Fs;
                    end
                    %funcion
                    x=A*sawtooth(2*pi*f*t,width);
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    plot(app.Grafico,t,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        plot(app.Grafico,t_1,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        plot(app.Grafico,t_1,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        plot(app.Grafico,t,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        plot(app.Grafico,t,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.Grafico,t,xa,'cyan');
                    end
                    Ex = trapz(abs(x).^2,t);
                    Px = (1/(t(end)-t(1)))*trapz(abs(x).^2,t);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                plot(app.Grafico,t,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                plot(app.Grafico,t,xm,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x)*(Fs/400);
                                %t(:,1:length(yt))
                                plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                y = cumsum(x) .* (t(2) - t(1));
                                plot(app.Grafico,t,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A.*sawtooth(2*pi*f*m*t,width);
                                plot(app.Grafico,t,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A.*sawtooth(2*pi*f*(t+re),width);
                                plot(app.Grafico,t,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2,'same');
                                t_conv = linspace(2*t(1), 2*t(end), length(xs));
                                plot(app.Grafico,t,x,'red');
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                plot(app.Grafico,x,'red');
                                plot(app.Grafico,xs,'blue');
                                plot(app.Grafico,grap2,'magenta');
                        end
                    end
                case 'Señal Senoidal'
                    A= app.edit1.Value; 
                    f=app.edit2.Value;
                    phi=app.edit3.Value;
                    Fs = app.edit6.Value;
                    switch peri
                            case 'Periodica'
                                t=app.edit5.Value:1/Fs:app.edit4.Value-1/Fs;
                                t_1=-app.edit4.Value:1/Fs:-app.edit5.Value-1/Fs;
                            case 'Aperiodica'
                                T=1/f;
                                t=app.edit5.Value:1/Fs:T-1/Fs;
                                t_1=-T:1/Fs:-app.edit5.Value-1/Fs;
                    end
                    %funcion
                    x = A*sin(2*pi*f*t+phi); 
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    plot(app.Grafico,t,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        plot(app.Grafico,t_1,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        plot(app.Grafico,t_1,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        plot(app.Grafico,t,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        plot(app.Grafico,t,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.Grafico,t,xa,'cyan');
                    end
                    Ex = trapz(abs(x).^2,t);
                    Px = (1/(t(end)-t(1)))*trapz(abs(x).^2,t);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                plot(app.Grafico,t,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                plot(app.Grafico,t,xm,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd = diff(x)*(Fs/600);
                                plot(app.Grafico,t(1:end-1),xd,'blue');
                            case 'Integración '
                                y = cumsum(x);
                                plot(app.Grafico,t,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A*sin(2*pi*f*m*(t+phi));
                                plot(app.Grafico,t,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A*sin(2*pi*f*((t+re)+phi));
                                plot(app.Grafico,t+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2,'same');
                                t_conv = linspace(2*t(1), 2*t(end), length(xs));
                                plot(app.Grafico,t,x,'red');
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                plot(app.Grafico,x,'red');
                                plot(app.Grafico,xs,'blue');
                                plot(app.Grafico,grap2,'magenta');
                        end
                    end
                case 'Exponencial Crecientey Decreciente'
                    Fs = app.edit6.Value;
                    t = app.edit2.Value:1/Fs:app.edit3.Value;
                    x1=exp(t);
                    r= app.edit1.Value;
                    x2 = r.^(-t);
                    t_1=-app.edit3.Value:1/Fs:-app.edit2.Value;
                    plot(app.UIAxes,t,x1,'red')
                    hold(app.UIAxes);
                    for k=1: length(x1)
                        x_1r(k)=x1(length(x1)-k+1);
                    end
                    if(app.cbx1.Value==1)
                        plot(app.UIAxes,t_1,x_1r,'black')
                    end
                    if(app.cbx2.Value==1)
                        plot(app.UIAxes,t_1,-x_1r,'blue')
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x1+x_1r);
                        plot(app.UIAxes,t,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x1-x_1r);
                        plot(app.UIAxes,t,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.UIAxes,t,xa,'cyan');
                    end
                    Ex = trapz(abs(x1).^2,t);
                    Px = (1/(t(end)-t(1)))*trapz(abs(x1).^2,t);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    
                    for v=1: length(x2)
                        x_2r(v)=x2(length(x2)-v+1);
                    end
                    plot(app.UIAxes_2,t,x2,'red');
                    hold(app.UIAxes_2);
                    if(app.cbx1.Value==1)
                        plot(app.UIAxes_2,t_1,x_2r,'black')
                    end
                    if(app.cbx2.Value==1)
                        plot(app.UIAxes_2,t_1,-x_2r,'blue')
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x2+x_2r);
                        plot(app.UIAxes_2,t,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x2-x_2r);
                        plot(app.UIAxes_2,t,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.UIAxes_2,t,xa,'cyan');
                    end
                    
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa1=a*x1;
                                xa2=a*x2;
                                plot(app.UIAxes,t,xa1,'blue');
                                plot(app.UIAxes_2,t,xa2,'blue');
                            case 'Suma'
                                xa1=x1+grap2;
                                xa2=x2+grap3;
                                plot(app.UIAxes,t,xa1,'blue');
                                plot(app.UIAxes_2,t,xa2,'blue');
                            case 'Multiplicación '
                                xa1=x1.*grap2;
                                xa2=x2.*grap3;
                                plot(app.UIAxes,t,xa1,'blue');
                                plot(app.UIAxes_2,t,xa2,'blue');
                            case 'Diferenciación Metodo aproximado'
                                xd1=diff(x1);
                                xd2=diff(x2);
                                %t(:,1:length(yt))
                                plot(app.UIAxes,t(:,1:length(xd1)),xd1,'blue');
                                plot(app.UIAxes_2,t(:,1:length(xd2)),xd2,'blue');
                            case 'Integración '
                                y1 = cumsum(x1) .* (t(2) - t(1));
                                y2 = cumsum(x2) .* (t(2) - t(1));
                                plot(app.UIAxes,t,y1,'blue');
                                plot(app.UIAxes_2,t,y2,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe1=exp(t*m);
                                xe2 = r.^(-t*m);
                                plot(app.UIAxes,t,xe1,'blue');
                                plot(app.UIAxes_2,t,xe2,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xe1=exp(t+re);
                                xe2 = r.^(-t+re);
                                plot(app.UIAxes,t,xe1,'blue');
                                plot(app.UIAxes_2,t,xe2,'blue');
                            case 'CONVOLUCIÓN'
                                xa1=conv(x1,grap2,'same');
                                if(grap3~=0)
                                    xa2=conv(x2,grap3,'same');
                                else
                                    xa2=conv(x2,grap2,'same');
                                end
                                t_conv1 = linspace(2*t(1), 2*t(end), length(xa1));
                                t_conv2 = linspace(2*t(1), 2*t(end), length(xa2));
                                plot(app.UIAxes,t,xa1,'blue');
                                plot(app.UIAxes_2,t,xa2,'blue');
                            case 'Correlaccion'
                                xa1=xcorr(x1,grap2,'none');
                                if(grap3~=0)
                                    xa2=xcorr(x2,grap3,'same');
                                else
                                    xa2=coxcorrnv(x2,grap2,'same');
                                end
                                plot(app.UIAxes,t,xa1,'blue');
                                plot(app.UIAxes_2,t,xa2,'blue');
                        end
                    end
                case 'Señal Exponencial Compleja'
                    opci=app.opciografica.Value;
                    Fs=app.edit6.Value;
                    t = app.edit1.Value*pi:1/Fs:app.edit2.Value*pi;
                    x = exp(1j.*(2.*t+(pi/4)));
                    t_1=-app.edit2.Value*pi:1/Fs:-app.edit1.Value*pi;
                    switch opci
                        case 'Cálculo de la parte Real de x(t)'
                            xRE=real(x);
                            for k=1: length(xRE)
                                x_1r(k)=xRE(length(xRE)-k+1);
                            end
                            plot(app.Grafico,t,xRE,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                plot(app.Grafico,t_1,x_1r,'black')
                            end
                            if(app.cbx2.Value==1)
                                plot(app.Grafico,t_1,-x_1r,'blue')
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(xRE+x_1r);
                                plot(app.Grafico,t,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(xRE-x_1r);
                                plot(app.Grafico,t,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                plot(app.Grafico,t,xa,'cyan');
                            end
                            Ex = trapz(abs(xRE).^2,t);
                            Px = (1/(t(end)-t(1)))*trapz(abs(xRE).^2,t);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                            %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*xRE;
                                        plot(app.Grafico,t,xa,'blue');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(xRE);
                                        %t(:,1:length(yt))
                                        plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                       y = cumsum(xRE) .* (t(2) - t(1));
                                       plot(app.Grafico,t,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=real(exp(1j.*(2.*m*t+(pi/4))));
                                        plot(app.Grafico,t,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=real(exp(1j.*(2.*(t+re)+(pi/4))));
                                        plot(app.Grafico,t+re,xr,'blue');
                                end
                            end
                            
                        case 'Cálculo de la parte imaginaria de x(t)'
                            xIM=imag(x);
                            for k=1: length(xIM)
                                x_1r(k)=xIM(length(xIM)-k+1);
                            end
                            plot(app.Grafico,t,xIM,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                plot(app.Grafico,t_1,x_1r,'black')
                            end
                            if(app.cbx2.Value==1)
                                plot(app.Grafico,t_1,-x_1r,'blue')
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(xIM+x_1r);
                                plot(app.Grafico,t,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(xIM-x_1r);
                                plot(app.Grafico,t,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                plot(app.Grafico,t,xa,'cyan');
                            end
                            Ex = trapz(abs(xIM).^2,t);
                            Px = (1/(t(end)-t(1)))*trapz(abs(xIM).^2,t);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                            %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*xIM;
                                        plot(app.Grafico,t,xa,'blue');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(xIM);
                                        %t(:,1:length(yt))
                                        plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(xIM) .* (t(2) - t(1));
                                        plot(app.Grafico,t,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=imag(exp(1j.*(2.*m*t+(pi/4))));
                                        plot(app.Grafico,t,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=imag(exp(1j.*(2.*(t+re)+(pi/4))));
                                        plot(app.Grafico,t+re,xr,'blue');
                                end
                            end
                            
                        case 'Cálculo de la magnitud de x(t)'
                            xMAG=abs(x);
                            for k=1: length(xMAG)
                                x_1r(k)=xMAG(length(xMAG)-k+1);
                            end
                            plot(app.Grafico,t,xMAG,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                plot(app.Grafico,t_1,x_1r,'black')
                            end
                            if(app.cbx2.Value==1)
                                plot(app.Grafico,t_1,-x_1r,'blue')
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(xMAG+x_1r);
                                plot(app.Grafico,t,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(xMAG-x_1r);
                                plot(app.Grafico,t,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                plot(app.Grafico,t,xa,'cyan');
                            end
                            Ex = trapz(abs(xMAG).^2,t);
                            Px = (1/(t(end)-t(1)))*trapz(abs(xMAG).^2,t);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                            %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*xMAG;
                                        plot(app.Grafico,t,xa,'blue');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(xMAG);
                                        %t(:,1:length(yt))
                                        plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(xMAG) .* (t(2) - t(1));
                                        plot(app.Grafico,t,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=abs(exp(1j.*(2.*m*t+(pi/4))));
                                        plot(app.Grafico,t,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=abs(exp(1j.*(2.*(t+re)+(pi/4))));
                                        plot(app.Grafico,t+re,xr,'blue');
                                end
                            end
                            
                        case 'Cálculo del Argumento de x(t)'
                            xARG = atan(imag(x)./real(x));
                            for k=1: length(xARG)
                                x_1r(k)=xARG(length(xARG)-k+1);
                            end
                            plot(app.Grafico,t,xARG,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                plot(app.Grafico,t_1,x_1r,'black')
                            end
                            if(app.cbx2.Value==1)
                                plot(app.Grafico,t_1,-x_1r,'blue')
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(xARG+x_1r);
                                plot(app.Grafico,t,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(xARG-x_1r);
                                plot(app.Grafico,t,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                plot(app.Grafico,t,xa,'cyan');
                            end
                            Ex = trapz(abs(xARG).^2,t);
                            Px = (1/(t(end)-t(1)))*trapz(abs(xARG).^2,t);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                            %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*xARG;
                                        plot(app.Grafico,t,xa,'blue');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(xARG);
                                        %t(:,1:length(yt))
                                        plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(xARG) .* (t(2) - t(1));
                                        plot(app.Grafico,t,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=atan(imag(exp(1j.*(2.*m*t+(pi/4))))./real(exp(1j.*(2.*m*t+(pi/4))))); %();
                                        plot(app.Grafico,t,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=atan(imag(exp(1j.*(2.*(t+re)+(pi/4))))./real(exp(1j.*(2.*(t+re)+(pi/4))))); %();
                                        plot(app.Grafico,t+re,xr,'blue');
                                end
                            end
                            
                    end
                case 'Generación de una señal Escalón'
                    A=app.edit1.Value;
                    opci=app.opciografica.Value;
                    t = app.edit2.Value:1/Fs:app.edit3.Value;
                    t_1=-app.edit3.Value:1/Fs:-app.edit2.Value;
                    switch opci
                        case 'Escalon con heaviside'
                            x1=A*heaviside(t);
                            for k=1: length(x1)
                                x_1r(k)=x1(length(x1)-k+1);
                            end
                            plot(app.Grafico,t,x1,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                plot(app.Grafico,t_1,x_1r,'black')
                            end
                            if(app.cbx2.Value==1)
                                plot(app.Grafico,t_1,-x_1r,'blue')
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(x1+x_1r);
                                plot(app.Grafico,t,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(x1-x_1r);
                                plot(app.Grafico,t,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                plot(app.Grafico,t,xa,'cyan');
                            end
                            Ex = trapz(abs(x1).^2,t);
                            Px = (1/(t(end)-t(1)))*trapz(abs(x1).^2,t);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                             %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*x1;
                                        plot(app.Grafico,t,xa,'blue');
                                    case 'Suma'
                                        xs=x1+grap2;
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Multiplicación '
                                        xs=x1.*grap2;
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(x1);
                                        %t(:,1:length(yt))
                                        plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(x1) .* (t(2) - t(1));
                                        plot(app.Grafico,t,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=A*heaviside(t*m);
                                        plot(app.Grafico,t,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=A*heaviside(t+re);
                                        plot(app.Grafico,t+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(x1,grap2,'same');
                                        t_conv = linspace(2*t(1), 2*t(end), length(xs));
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Correlaccion'
                                        xs=xcorr(x1,grap2,'none');
                                        plot(app.Grafico,x1,'red');
                                        plot(app.Grafico,xs,'blue');
                                        plot(app.Grafico,grap2,'magenta');
                                        end
                            end
                            
                        case 'Escalon con ones'
                            x2=A.*ones(1,length(t));
                            for k=1: length(x2)
                                x_1r(k)=x2(length(x2)-k+1);
                            end
                            plot(app.Grafico,t,x2,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                plot(app.Grafico,t_1,x_1r,'black')
                            end
                            if(app.cbx2.Value==1)
                                plot(app.Grafico,t_1,-x_1r,'blue')
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(x2+x_1r);
                                plot(app.Grafico,t,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(x2-x_1r);
                                plot(app.Grafico,t,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                plot(app.Grafico,t,xa,'cyan');
                            end
                            Ex = trapz(abs(x2).^2,t);
                            Px = (1/(t(end)-t(1)))*trapz(abs(x2).^2,t);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                             %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*x2;
                                        plot(app.Grafico,t,xa,'blue');
                                    case 'Suma'
                                        xs=x2+grap2;
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Multiplicación '
                                        xs=x2.*grap2;
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(x2);
                                        %t(:,1:length(yt))
                                        plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(x2) .* (t(2) - t(1));
                                        plot(app.Grafico,t,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=A*heaviside(t*m);
                                        plot(app.Grafico,t,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=A*heaviside(t+re);
                                        plot(app.Grafico,t+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(x2,grap2,'same');
                                        t_conv = linspace(2*t(1), 2*t(end), length(xs));
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Correlaccion'
                                        xs=xcorr(x2,grap2,'none');
                                        plot(app.Grafico,x2,'red');
                                        plot(app.Grafico,xs,'blue');
                                        plot(app.Grafico,grap2,'magenta');
                                end
                            end
                        case 'Escalon con ADSP'
                            unitstep = t>=0;
                            x3 = A.*unitstep;
                            for k=1: length(x3)
                                x_1r(k)=x3(length(x3)-k+1);
                            end
                            plot(app.Grafico,t,x3,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                plot(app.Grafico,t_1,x_1r,'black')
                            end
                            if(app.cbx2.Value==1)
                                plot(app.Grafico,t_1,-x_1r,'blue')
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(x3+x_1r);
                                plot(app.Grafico,t,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(x3-x_1r);
                                plot(app.Grafico,t,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                plot(app.Grafico,t,xa,'cyan');
                            end
                            Ex = trapz(abs(x3).^2,t);
                            Px = (1/(t(end)-t(1)))*trapz(abs(x3).^2,t);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                             %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*x3;
                                        plot(app.Grafico,t,xa,'blue');
                                   case 'Suma'
                                        xs=x3+grap2;
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Multiplicación '
                                        xs=x3.*grap2;
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(x3);
                                        %t(:,1:length(yt))
                                        plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(x3) .* (t(2) - t(1));
                                        plot(app.Grafico,t,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=A*heaviside(t*m);
                                        plot(app.Grafico,t,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=A*heaviside(t+re);
                                        plot(app.Grafico,t+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(x3,grap2,'same');
                                        t_conv = linspace(2*t(1), 2*t(end), length(xs));
                                        plot(app.Grafico,t,xs,'blue');
                                        plot(app.Grafico,t,grap2,'magenta');
                                    case 'Correlaccion'
                                        xs=xcorr(x3,grap2,'none');
                                        plot(app.Grafico,x3,'red');
                                        plot(app.Grafico,xs,'blue');
                                        plot(app.Grafico,grap2,'magenta');
                                end
                            end
                            
                    end
                case 'Generación de una señal Impulso'
                    A=app.edit1.Value;
                    n=app.edit2.Value:app.edit3.Value;
                    x1 = A.*[zeros(1,3),ones(1,1),zeros(1,2)];
                    x2=(n-3)==0;
                    n_2=-app.edit3.Value:-app.edit2.Value;
                    for k=1: length(x1)
                        x_1r(k)=x1(length(x1)-k+1);
                    end
                    plot(app.UIAxes,n,x1,'red');
                    hold(app.UIAxes);
                    if(app.cbx1.Value==1)
                        plot(app.UIAxes,n_2,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        plot(app.UIAxes,n_2,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x1+x_1r);
                        plot(app.UIAxes,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x1-x_1r);
                        plot(app.UIAxes,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.UIAxes,n,xa,'cyan');
                    end
                    Ex = trapz(abs(x1).^2,n);
                    Px = (1/(n(end)-n(1)))*trapz(abs(x1).^2,n);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    
                    for v=1: length(x2)
                        x_2r(v)=x2(length(x2)-v+1);
                    end
                    plot(app.UIAxes_2,n,x2,'red');
                    hold(app.UIAxes_2);
                    if(app.cbx1.Value==1)
                        plot(app.UIAxes_2,n_2,x_2r,'black');
                    end
                    if(app.cbx2.Value==1)
                        plot(app.UIAxes_2,n_2,-x_2r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe1=(1/2)*(x2+x_2r);
                        plot(app.UIAxes_2,n,xe1,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo1=(1/2)*(x2-x_2r);
                        plot(app.UIAxes_2,n,xo1,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe1+xo1;
                        plot(app.UIAxes_2,n,xa,'cyan');
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa1=a*x1;
                                xa2=a*x2;
                                plot(app.UIAxes,n,xa1,'blue');
                                plot(app.UIAxes_2,n,xa2,'blue');
                            case 'Suma'
                                xa1=x1+grap2;
                                xa2=x2+grap3;
                                plot(app.UIAxes,n,xa1,'blue');
                                plot(app.UIAxes_2,n,xa2,'blue');
                            case 'Multiplicación '
                                xa1=x1.*grap2;
                                xa2=x2.*grap3;
                                plot(app.UIAxes,n,xa1,'blue');
                                plot(app.UIAxes_2,n,xa2,'blue');
                            case 'Diferenciación Metodo aproximado'
                                xd1=diff(x1);
                                xd2=diff(x2);
                                %t(:,1:length(yt))
                                plot(app.UIAxes,t(:,1:length(xd1)),xd1,'blue');
                                plot(app.UIAxes_2,t(:,1:length(xd2)),xd2,'blue');
                            case 'Integración '
                                y1 = cumsum(x1) .* (t(2) - t(1));
                                y2 = cumsum(x2) .* (t(2) - t(1));
                                plot(app.UIAxes,t,y1,'blue');
                                plot(app.UIAxes_2,t,y2,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe1 = (A).*[zeros(1,3),ones(1,1),zeros(1,2)];
                                xe2=(n-3)==0;
                                plot(app.UIAxes,t,xe1,'blue');
                                plot(app.UIAxes_2,t,xe2,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xe1=exp(t+re);
                                xe2 = r.^(-t+re);
                                plot(app.UIAxes,t,xe1,'blue');
                                plot(app.UIAxes_2,t,xe2,'blue');
                            case 'CONVOLUCIÓN'
                                xa1=conv(x1,grap2);
                                if(grap3~=0)
                                    xa2=conv(x2,grap3,'same');
                                else
                                    xa2=conv(x2,grap2,'same');
                                end
                                t_conv1 = linspace(2*t(1), 2*t(end), length(xa1));
                                t_conv2 = linspace(2*t(1), 2*t(end), length(xa2));
                                plot(app.UIAxes,t_conv1,xa1,'blue');
                                plot(app.UIAxes_2,t_conv2,xa2,'blue');
                            case 'Correlaccion'
                                xa1=xcorr(x1,grap2,'none');
                                if(grap3~=0)
                                    xa2=xcorr(x2,grap3,'same');
                                else
                                    xa2=coxcorrnv(x2,grap2,'same');
                                end
                                plot(app.UIAxes,t,xa1,'blue');
                                plot(app.UIAxes_2,t,xa2,'blue');
                        end
                    end
                case 'Generación de una señal Rampa'
                    A=app.edit1.Value;
                    t1=app.edit2.Value:1/Fs:5;
                    t2=5:1/Fs:app.edit3.Value;
                    r=t1;
                    t=[t1,t2];
                    t_1=-t;
                    x1 = A.*[zeros(1,length(t1)),r];
                    plot(app.Grafico,t,x1,'red')
                    hold(app.Grafico);
                    for k=1: length(x1)
                        x_1r(k)=x1(length(x1)-k+1);
                    end
                    plot(app.Grafico,t,x1,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        plot(app.Grafico,t_1,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        plot(app.Grafico,t_1,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x1+x_1r);
                        plot(app.Grafico,t,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x1-x_1r);
                        plot(app.Grafico,t,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.Grafico,t,xa,'cyan');
                    end
                    Ex = trapz(abs(x1).^2,t);
                    Px = (1/(t(end)-t(1)))*trapz(abs(x1).^2,t);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x1;
                                plot(app.Grafico,t,xa,'blue');
                            case 'Suma'
                                xs=x1+grap2;
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x1.*grap2;
                                plot(app.Grafico,t,xm,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x1);
                                %t(:,1:length(yt))
                                plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                y = cumsum(x1) .* (t(2) - t(1));
                                plot(app.Grafico,t,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A.*[zeros(1,length(t1*m)),r];
                                plot(app.Grafico,t,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A.*[zeros(1,length(t1+re)),r];
                                plot(app.Grafico,t+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x1,grap2);
                                t_conv1 = linspace(2*t(1), 2*t(end), length(xs));
                                plot(app.Grafico,t_conv1,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x1,grap2,'none');
                                plot(app.Grafico,x1,'red');
                                plot(app.Grafico,xs,'blue');
                                plot(app.Grafico,grap2,'magenta');
                        end
                    end
                    
                case 'Generación de una señal Rectangular'
                    A=app.edit1.Value;
                    Fs=app.edit6.Value;
                    t=app.edit2.Value:1/Fs:app.edit3.Value;
                    x=A.*rectpuls(t);
                    t_1=-app.edit3.Value:1/Fs:-app.edit2.Value;
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    plot(app.Grafico,t,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        plot(app.Grafico,t_1,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        plot(app.Grafico,t_1,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        plot(app.Grafico,t,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        plot(app.Grafico,t,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.Grafico,t,xa,'cyan');
                    end
                    Ex = trapz(abs(x).^2,t);
                    Px = (1/(t(end)-t(1)))*trapz(abs(x).^2,t);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                plot(app.Grafico,t,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                plot(app.Grafico,t,xm,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x);
                                %t(:,1:length(yt))
                                plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                %posible solucion
                                y = cumsum(x) .* (t(2) - t(1));
                                plot(app.Grafico,t,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A.*rectpuls(t*m);
                                plot(app.Grafico,t,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A.*rectpuls(t+re);
                                plot(app.Grafico,t+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2);
                                t_conv1 = linspace(2*t(1), 2*t(end), length(xs));
                                plot(app.Grafico,t_conv1,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                plot(app.Grafico,x,'red');
                                plot(app.Grafico,xs,'blue');
                                plot(app.Grafico,grap2,'magenta');
                        end
                    end
                    

                case 'Generación de una señal Triangular'
                    A=app.edit1.Value;
                    Fs=app.edit6.Value;
                    t=app.edit2.Value:1/Fs:app.edit3.Value;
                    x=A.*tripuls(t);
                    t_1=-app.edit3.Value:1/Fs:-app.edit2.Value;
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    plot(app.Grafico,t,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        plot(app.Grafico,t_1,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        plot(app.Grafico,t_1,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        plot(app.Grafico,t,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        plot(app.Grafico,t,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        plot(app.Grafico,t,xa,'cyan');
                    end
                    Ex = trapz(abs(x).^2,t);
                    Px = (1/(t(end)-t(1)))*trapz(abs(x).^2,t);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                plot(app.Grafico,t,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                plot(app.Grafico,t,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                plot(app.Grafico,t,xm,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x);
                                %t(:,1:length(yt))
                                plot(app.Grafico,t(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                %posible solucion
                                y = cumsum(x) .* (t(2) - t(1));
                                plot(app.Grafico,t,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A*tripuls(t*m);
                                plot(app.Grafico,t,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A*tripuls(t+re);
                                plot(app.Grafico,t+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2);
                                t_conv1 = linspace(2*t(1), 2*t(end), length(xs));
                                plot(app.Grafico,t_conv1,xs,'blue');
                                plot(app.Grafico,t,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                plot(app.Grafico,x,'red');
                                plot(app.Grafico,xs,'blue');
                                plot(app.Grafico,grap2,'magenta');
                        end
                    end
                otherwise
            end
        end

        % Value changed function: listiempo
        function listiempoValueChanged(app, event)
            val2=app.listiempo.Value; %get
            switch val2
                        case 'Tiempo continuo'
                            app.edit1.Value = 0;
                            app.edit2.Value = 0;
                            app.edit3.Value = 0;
                            app.edit4.Value = 0;
                            app.edit5.Value = 0;
                            app.FrecuenciadeMuestreoEditFieldLabel.Visible = 'on';
                            app.edit6.Visible= 'on';
                            app.btngrafico1.Enable='on';
                            app.btngrafico1.Visible='on';
                            app.btngrafico2.Enable='off';
                            app.btngrafico2.Visible='off';
                        case 'Tiempo discreto'
                            app.edit1.Value = 0;
                            app.edit2.Value = 0;
                            app.edit3.Value = 0;
                            app.edit4.Value = 0;
                            app.edit5.Value = 0;
                            app.FrecuenciadeMuestreoEditFieldLabel.Visible = 'off';
                            app.edit6.Visible= 'off';
                            app.btngrafico2.Enable='on';
                            app.btngrafico2.Visible='on';
                            app.btngrafico1.Enable='off';
                            app.btngrafico1.Visible='off';
   
                otherwise
%---------------------------------------------------------------------------
%---------------------------------------------------------------------------
%---------------------------------------------------------------------------
%---------------------------------------------------------------------------
%---------------------------------------------------------------------------
%---------------------------------------------------------------------------
%---------------------------------------------------------------------------
            end
        end

        % Button pushed function: btngrafico2
        function btngrafico2ButtonPushed(app, event)
            app.Lamp1.Enable = 'off';
            app.Lamp2.Enable = 'off';
            app.Lamp3.Enable = 'off';
            grap3=0;
            val=app.lisTipo.Value;
            peri=app.Switch.Value;
            val2=app.lisTipo_2.Value;
            switch val2
                case 'Señal Senoidal'
                        A2=app.edit7.Value;
                        f = app.edit8.Value;
                        phi = app.edit9.Value;
                        a=app.edit11.Value;
                        b=app.edit10.Value;
                        switch peri
                            case 'Periodica'
                                n=a:b;
                            case 'Aperiodica'
                                T=1/f;
                                n=a:T;
                        end
                        grap2 =A2*cos(2*pi*f*n-phi);
                        for k=1: length(grap2)
                            grap2_1r(k)=grap2(length(grap2)-k+1);
                        end
                case 'Señal Exponencial Compleja'
                        a=app.edit8.Value;
                        b=app.edit7.Value;
                        n = a*pi:0.1:b*pi;
                        x3 = exp(1j.*(2.*n+(pi/4)));
                        opci3=app.opciografica_2.Value;
                        switch opci3
                            case 'Cálculo de la parte Real de x(t)'
                                grap2=real(x3);
                            case 'Cálculo de la parte imaginaria de x(t)'
                                grap2=imag(x3);
                            case 'Cálculo de la magnitud de x(t)'
                                grap2=abs(x3);
                            case 'Cálculo del Argumento de x(t)'
                                grap2=atan(imag(x3)./real(x3));
                        end
                        for k=1: length(grap2)
                            grap2_1r(k)=grap2(length(grap2)-k+1);
                        end
                case 'Señal Cuadrada'
                        A2= app.edit7.Value; 
                        f=app.edit8.Value;
                        a=app.edit11.Value;
                        b=app.edit10.Value;
                        switch peri
                            case 'Periodica'
                                n=a:b; 
                            case 'Aperiodica'
                                T=1/f;
                                n=a:T;
                        end
                        duty= app.edit9.Value;
                        grap2 =A2*square(2*pi*f*n,duty);
                        for k=1: length(grap2)
                            grap2_1r(k)=grap2(length(grap2)-k+1);
                        end
                case 'Diente de Sierra'
                        A2= app.edit7.Value;
                        f2= app.edit8.Value;
                        a=app.edit11.Value;
                        b=app.edit10.Value;
                        switch peri
                            case 'Periodica'
                                n=a:b;
                            case 'Aperiodica'
                                T=1/f2;
                                n=a:T;
                        end
                        width2=app.edit9.Value;
                        grap2 =A2*sawtooth(2*pi*f2*n,width2);
                        for k=1: length(grap2)
                            grap2_1r(k)=grap2(length(grap2)-k+1);
                        end
                case 'Exponencial Crecientey Decreciente'
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        n=a:b;
                        grap2=exp(n);
                        for k=1: length(grap2)
                            grap2_1r(k)=grap2(length(grap2)-k+1);
                        end
                        r=app.edit7.Value;
                        grap3 = r.^(-n);
                case 'Generación de una señal Rampa'
                        A2=app.edit7.Value;
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        n1=a:(b/2);
                        r=n1;
                        grap2 = A2.*[zeros(1,length(n1)),r];
                        for k=1: length(grap2)
                            grap2_1r(k)=grap2(length(grap2)-k+1);
                        end
                case 'Generación de una señal Escalón'
                        A2=app.edit7.Value;
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        n= a:b;
                        grap2 = A2*heaviside(n);
                case 'Generación de una señal Impulso'
                    A2=app.edit7.Value;
                    a=app.edit9.Value;
                    b=app.edit8.Value;
                    n2=a:b;
                    grap2 = A2.*[zeros(1,3),ones(1,1),zeros(1,2)];
                    grap3=(n2-3)==0;
                    for k=1: length(grap2)
                        grap2_1r(k)=grap2(length(grap2)-k+1);
                    end
                case 'Generación de una señal Triangular'
                        A2=app.edit7.Value;
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        n=a:0.1:b;
                        grap2 = A2*tripuls(n);
                        for k=1: length(grap2)
                            grap2_1r(k)=grap2(length(grap2)-k+1);
                        end
                case 'Generación de una señal Rectangular'
                        A2=app.edit7.Value;
                        a=app.edit9.Value;
                        b=app.edit8.Value;
                        n=a:0.1:b;
                        grap2 = A2.*rectpuls(n);
                        for k=1: length(grap2)
                            grap2_1r(k)=grap2(length(grap2)-k+1);
                        end
                case ''
            end
            switch val
                case 'Seleciopnar opcion'
                case 'Señal Cuadrada'
                    A= app.edit1.Value; 
                    f=app.edit2.Value;
                    duty= app.edit3.Value;
                        switch peri
                            case 'Periodica'
                                n=app.edit5.Value:app.edit4.Value;
                                n_2=-app.edit4.Value:-app.edit5.Value; 
                            case 'Aperiodica'
                                T=1/f;
                                n=app.edit5.Value:T;
                                n_2=-T:-app.edit5.Value;
                        end
                    x=A*square(2*pi*f*n,duty);
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    stem(app.Grafico,n,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        stem(app.Grafico,n_2,x_1r,'black');
                        
                    end
                    if(app.cbx2.Value==1)
                        stem(app.Grafico,n_2,-x_1r,'blue');
                        
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        stem(app.Grafico,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        stem(app.Grafico,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.Grafico,n,xa,'cyan');
                    end
                    Ex = sum(abs(x).^2);
                    Px = Ex/length(x);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                stem(app.Grafico,n,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                stem(app.Grafico,n,xs,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                stem(app.Grafico,n,xm,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x).*(1/esc);
                                %t(:,1:length(yt))
                                stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                %posible solucion
                                y = cumsum(x) .* (n(2) - n(1));
                                stem(app.Grafico,n,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A.*square(2*pi*f*m*n,duty);
                                stem(app.Grafico,n,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A.*square(2*pi*f*(n+re),duty);
                                stem(app.Grafico,n+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2);
                                stem(app.Grafico,n,x,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                stem(app.Grafico,x,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                        end
                    end
                case 'Diente de Sierra'
                    A= app.edit1.Value; 
                    f=app.edit2.Value;
                    width=app.edit3.Value;
                    switch peri
                            case 'Periodica'
                                n=app.edit5.Value:app.edit4.Value;
                                n_2=-app.edit4.Value:-app.edit5.Value; 
                            case 'Aperiodica'
                                T=1/f;
                                n=app.edit5.Value:T;
                                n_2=-T:-app.edit5.Value;
                    end
                    x=A*sawtooth(2*pi*f*n,width);
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    stem(app.Grafico,n,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        stem(app.Grafico,n_2,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.Grafico,n_2,-x_1r,'blue');
                    end  

                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        stem(app.Grafico,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        stem(app.Grafico,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.Grafico,n,xa,'cyan');
                    end
                    Ex = sum(abs(x).^2);
                    Px = Ex/length(x);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                stem(app.Grafico,n,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                stem(app.Grafico,n,xs,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                stem(app.Grafico,n,xm,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x);
                                %t(:,1:length(yt))
                                stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                y = cumsum(x) .* (n(2) - n(1));
                                stem(app.Grafico,n,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A.*sawtooth(2*pi*f*m*n,width);
                                stem(app.Grafico,n,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A.*sawtooth(2*pi*f*(n+re),width);
                                stem(app.Grafico,n,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2);
                                stem(app.Grafico,n,x,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                stem(app.Grafico,x,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                        end
                    end
    
                case 'Señal Senoidal'
                    A= app.edit1.Value; 
                    f=app.edit2.Value;
                    phi=app.edit3.Value;
                    switch peri
                            case 'Periodica'
                                n=app.edit5.Value:app.edit4.Value;
                                n_2=-app.edit4.Value:-app.edit5.Value; 
                            case 'Aperiodica'
                                T=1/f;
                                n=app.edit5.Value:T;
                                n_2=-T:-app.edit5.Value;
                    end
                    x=A.*cos(2*pi*f*(n-phi));
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    stem(app.Grafico,n,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        stem(app.Grafico,n_2,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.Grafico,n_2,-x_1r,'blue');
                    end
                    xe=(1/2)*(x+x_1r);
                    xo=(1/2)*(x-x_1r);
                    if(app.cbx3.Value==1)
                        stem(app.Grafico,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        stem(app.Grafico,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.Grafico,n,xa,'cyan');
                    end
                    Ex = sum(abs(x).^2);
                    Px = Ex/length(x);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                stem(app.Grafico,n,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                stem(app.Grafico,n,xs,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                stem(app.Grafico,n,xm,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x);
                                %t(:,1:length(yt))
                                stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                y = cumsum(x);
                                stem(app.Grafico,n,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A*cos(2*pi*f*(n*m)-phi);
                                stem(app.Grafico,n,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A*cos(2*pi*f*(n+re)-phi);
                                stem(app.Grafico,n+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2);
                                stem(app.Grafico,n,x,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                stem(app.Grafico,x,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                        end
                    end
                    
                case 'Exponencial Crecientey Decreciente'
                    n=app.edit2.Value:app.edit3.Value;
                    x1=exp(n);
                    n_2=-app.edit3.Value:-app.edit2.Value;
                    for k=1: length(x1)
                        x_1r(k)=x1(length(x1)-k+1);
                    end
                    stem(app.UIAxes,n,x1,'red');
                    hold(app.UIAxes);
                    if(app.cbx1.Value==1)
                        stem(app.UIAxes,n_2,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.UIAxes,n_2,-x_1r,'blue');
                    end
                       
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x1+x_1r);
                        stem(app.UIAxes,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x1-x_1r);
                        stem(app.UIAxes,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.UIAxes,n,xa,'cyan');
                    end
                    Ex = sum(abs(x1).^2);
                    Px = Ex/length(x1);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    
                    r=app.edit1.Value;
                    x2 = r.^(-n);
                    for v=1: length(x2)
                        x_2r(v)=x2(length(x2)-v+1);
                    end
                    stem(app.UIAxes_2,n,x2,'red');
                    hold(app.UIAxes_2);
                    if(app.cbx1.Value==1)
                        stem(app.UIAxes_2,n_2,x_2r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.UIAxes_2,n_2,-x_2r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x2+x_2r);
                        stem(app.UIAxes_2,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x2-x_2r);
                        stem(app.UIAxes_2,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.UIAxes_2,n,xa,'cyan');
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa1=a*x1;
                                xa2=a*x2;
                                stem(app.UIAxes,n,xa1,'blue');
                                stem(app.UIAxes_2,n,xa2,'blue');
                            case 'Suma'
                                xa1=x1+grap2;
                                xa2=x2+grap3;
                                stem(app.UIAxes,n,xa1,'blue');
                                stem(app.UIAxes_2,n,xa2,'blue');
                            case 'Multiplicación '
                                xa1=x1.*grap2;
                                xa2=x2.*grap3;
                                stem(app.UIAxes,n,xa1,'blue');
                                stem(app.UIAxes_2,n,xa2,'blue');
                            case 'Diferenciación Metodo aproximado'
                                xd1=diff(x1);
                                xd2=diff(x2);
                                %t(:,1:length(yt))
                                stem(app.UIAxes,n(:,1:length(xd1)),xd1,'blue');
                                stem(app.UIAxes_2,n(:,1:length(xd2)),xd2,'blue');
                            case 'Integración '
                                y1 = cumsum(x1) .* (n(2) - n(1));
                                y2 = cumsum(x2) .* (n(2) - n(1));
                                stem(app.UIAxes,n,y1,'blue');
                                stem(app.UIAxes_2,n,y2,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe1=exp(n*m);
                                xe2 = r.^(-n*m);
                                stem(app.UIAxes,n,xe1,'blue');
                                stem(app.UIAxes_2,n,xe2,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xe1=exp(n+re);
                                xe2 = r.^(-n+re);
                                stem(app.UIAxes,n,xe1,'blue');
                                stem(app.UIAxes_2,n,xe2,'blue');
                            case 'CONVOLUCIÓN'
                                xs1=conv(x1,grap2);
                                if(grap3~=0)
                                    xs2=conv(x2,grap3);
                                else
                                    xs2=conv(x2,grap2);
                                end
                                stem(app.UIAxes,xs1,'blue');
                                stem(app.UIAxes_2,xs2,'blue');
                            case 'Correlaccion'
                                xs1=xcorr(x1,grap2,'none');
                                if(grap3~=0)
                                    xs2=xcorr(x2,grap3,'none');
                                else
                                    xs2=xcorr(x2,grap2,'none');
                                end
                                stem(app.UIAxes,xs1,'blue');
                                stem(app.UIAxes_2,xs2,'blue');
                        end
                    end
                    
                case 'Señal Exponencial Compleja'
                    opci=app.opciografica.Value;
                    n = app.edit1.Value*pi:0.1:app.edit2.Value*pi;
                    x = exp(1j.*(2.*n+(pi/4)));
                    n_2=-app.edit2.Value*pi:0.1:-app.edit1.Value*pi;
                    
                    switch opci
                        case 'Cálculo de la parte Real de x(t)'
                            xRE=real(x);
                            for k=1: length(xRE)
                                x_1r(k)=xRE(length(xRE)-k+1);
                            end
                            stem(app.Grafico,n,xRE,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                stem(app.Grafico,n,x_1r,'black');
                            end
                            if(app.cbx2.Value==1)
                                stem(app.Grafico,n,-x_1r,'blue');
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(xRE+x_1r);
                                stem(app.Grafico,n,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(xRE-x_1r);
                                stem(app.Grafico,n,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                stem(app.Grafico,n,xa,'cyan');
                            end
                            Ex = sum(abs(xRE).^2);
                            Px = Ex/length(xRE);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                            %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*xRE;
                                        stem(app.Grafico,n,xa,'blue');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(xRE);
                                        %t(:,1:length(yt))
                                        stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(xRE) .* (n(2) - n(1));
                                        stem(app.Grafico,n,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=real(exp(1j.*(2.*m*n+(pi/4))));
                                        stem(app.Grafico,n,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=real(exp(1j.*(2.*(n+re)+(pi/4))));
                                        stem(app.Grafico,n+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(xRE,grap2);
                                        stem(app.Grafico,n,xRE,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                    case 'Correlaccion'
                                        xs=xcorr(xRE,grap2,'none');
                                        stem(app.Grafico,xRE,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                    
                                end
                            end
                        case 'Cálculo de la parte imaginaria de x(t)'
                            xIM=imag(x);
                            for k=1: length(xIM)
                                x_1r(k)=xIM(length(xIM)-k+1);
                            end
                            stem(app.Grafico,n,xIM,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                stem(app.Grafico,n,x_1r,'black');
                            end
                            if(app.cbx2.Value==1)
                                stem(app.Grafico,n,-x_1r,'blue');
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(xIM+x_1r);
                                stem(app.Grafico,n,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(xIM-x_1r);
                                stem(app.Grafico,n,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                stem(app.Grafico,n,xa,'cyan');
                            end
                            Ex = sum(abs(xIM).^2);
                            Px = Ex/length(xIM);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                            %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*xIM;
                                        stem(app.Grafico,n,xa,'blue');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(xIM);
                                        stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(xIM) .* (n(2) - n(1));
                                        stem(app.Grafico,n,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=imag(exp(1j.*(2.*m*n+(pi/4))));
                                        stem(app.Grafico,n,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=imag(exp(1j.*(2.*(n+re)+(pi/4))));
                                        stem(app.Grafico,n+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(xIM,grap2);
                                        stem(app.Grafico,n,xIM,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                    case 'Correlaccion'
                                        xs=xcorr(xIM,grap2,'none');
                                        stem(app.Grafico,xIM,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                end
                            end
                        case 'Cálculo de la magnitud de x(t)'
                            xMAG=abs(x);
                            for k=1: length(xMAG)
                                x_1r(k)=xMAG(length(xMAG)-k+1);
                            end
                            stem(app.Grafico,n,xMAG,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                stem(app.Grafico,n,x_1r,'black');
                            end
                            if(app.cbx2.Value==1)
                                stem(app.Grafico,n,-x_1r,'blue');
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(xMAG+x_1r);
                                stem(app.Grafico,n,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(xMAG-x_1r);
                                stem(app.Grafico,n,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                stem(app.Grafico,n,xa,'cyan');
                            end
                            Ex = sum(abs(xMAG).^2);
                            Px = Ex/length(xMAG);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                            %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*xMAG;
                                        stem(app.Grafico,n,xa,'blue');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(xMAG);
                                        %t(:,1:length(yt))
                                        stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(xMAG) .* (n(2) - n(1));
                                        stem(app.Grafico,n,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=abs(exp(1j.*(2.*m*n+(pi/4))));
                                        stem(app.Grafico,n,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=abs(exp(1j.*(2.*(n+re)+(pi/4))));
                                        stem(app.Grafico,n+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(xMAG,grap2);
                                        stem(app.Grafico,n,xMAG,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                    case 'Correlaccion'
                                        xs=xcorr(xMAG,grap2,'none');
                                        stem(app.Grafico,xMAG,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                end
                            end
                            
                        case 'Cálculo del Argumento de x(t)'
                            xARG = atan(imag(x)./real(x));
                            for k=1: length(xARG)
                                x_1r(k)=xARG(length(xARG)-k+1);
                            end
                            stem(app.Grafico,n,xARG,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                stem(app.Grafico,n,x_1r,'black');
                            end
                            if(app.cbx2.Value==1)
                                stem(app.Grafico,n,-x_1r,'blue');
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(xARG+x_1r);
                                stem(app.Grafico,n,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(xARG-x_1r);
                                stem(app.Grafico,n,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                stem(app.Grafico,n,xa,'cyan');
                            end
                            Ex = sum(abs(xARG).^2);
                            Px = Ex/length(xARG);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                            %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*xARG;
                                        stem(app.Grafico,n,xa,'blue');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(xARG);
                                        %t(:,1:length(yt))
                                        stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(xARG) .* (n(2) - n(1));
                                        stem(app.Grafico,n,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=atan(imag(exp(1j.*(2.*m*n+(pi/4))))./real(exp(1j.*(2.*m*t+(pi/4))))); %();
                                        stem(app.Grafico,n,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=atan(imag(exp(1j.*(2.*(n+re)+(pi/4))))./real(exp(1j.*(2.*(t+re)+(pi/4))))); %();
                                        stem(app.Grafico,n+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(xARG,grap2);
                                        stem(app.Grafico,n,xARG,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                    case 'Correlaccion'
                                        xs=xcorr(xARG,grap2,'none');
                                        stem(app.Grafico,xARG,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                end
                            end
                           
                    end
                case 'Generación de una señal Escalón'
                    A=app.edit1.Value;
                    opci=app.opciografica.Value;
                    n= app.edit2.Value:app.edit3.Value;
                    n_1=-app.edit3.Value:-app.edit2.Value;
                    switch opci
                        case 'Escalon con heaviside'
                            x1=A*heaviside(n);
                            for k=1: length(x1)
                                x_1r(k)=x1(length(x1)-k+1);
                            end
                            stem(app.Grafico,n,x1,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                stem(app.Grafico,n_1,x_1r,'black');
                            end
                            if(app.cbx2.Value==1)
                                stem(app.Grafico,n_1,-x_1r,'blue');
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(x1+x_1r);
                                stem(app.Grafico,n,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(x1-x_1r);
                                stem(app.Grafico,n,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                stem(app.Grafico,n,xa,'cyan');
                            end
                            Ex = sum(abs(x1).^2);
                            Px = Ex/length(x1);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                             %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*x1;
                                        stem(app.Grafico,n,xa,'blue');
                                    case 'Suma'
                                        xs=x1+grap2;
                                        stem(app.Grafico,n,xs,'blue');
                                        stem(app.Grafico,n,grap2,'magenta');
                                    case 'Multiplicación '
                                        xs=x1.*grap2;
                                        stem(app.Grafico,n,xs,'blue');
                                        stem(app.Grafico,n,grap2,'magenta');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(x1);
                                        %t(:,1:length(yt))
                                        stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(x1) .* (n(2) - n(1));
                                        stem(app.Grafico,n,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=A*heaviside(n*m);
                                        stem(app.Grafico,n,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=A*heaviside(n+re);
                                        stem(app.Grafico,n+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(x1,grap2);
                                        stem(app.Grafico,grap2,'magenta');
                                        stem(app.Grafico,n,x1,'red');
                                        stem(app.Grafico,xs,'blue');
                                    case 'Correlaccion'
                                        xs=xcorr(x1,grap2,'none');
                                        stem(app.Grafico,x1,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                end
                            end
    
                            
                        case 'Escalon con ones'
                            x2=A.*ones(1,length(n));
                            for k=1: length(x2)
                                x_1r(k)=x2(length(x2)-k+1);
                            end
                            stem(app.Grafico,n,x2,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                stem(app.Grafico,n_1,x_1r,'black');
                            end
                            if(app.cbx2.Value==1)
                                stem(app.Grafico,n_1,-x_1r,'blue');
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(x2+x_1r);
                                stem(app.Grafico,n,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(x2-x_1r);
                                stem(app.Grafico,n,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                stem(app.Grafico,n,xa,'cyan');
                            end
                            Ex = sum(abs(x2).^2);
                            Px = Ex/length(x2);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                             %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*x2;
                                        stem(app.Grafico,n,xa,'blue');
                                    case 'Suma'
                                        xs=x2+grap2;
                                        stem(app.Grafico,n,xs,'blue');
                                        stem(app.Grafico,n,grap2,'magenta');
                                    case 'Multiplicación '
                                        xs=x2.*grap2;
                                        stem(app.Grafico,n,xs,'blue');
                                        stem(app.Grafico,n,grap2,'magenta');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(x2);
                                        %t(:,1:length(yt))
                                        stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(x2) .* (n(2) - n(1));
                                        stem(app.Grafico,n,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=A*heaviside(n*m);
                                        stem(app.Grafico,n,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=A*heaviside(n+re);
                                        stem(app.Grafico,n+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(x2,grap2);
                                        stem(app.Grafico,grap2,'magenta');
                                        stem(app.Grafico,n,x2,'red');
                                        stem(app.Grafico,xs,'blue');
                                    case 'Correlaccion'
                                        xs=xcorr(x2,grap2,'none');
                                        stem(app.Grafico,x2,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                end
                            end
                            
                        case 'Escalon con ADSP'
                            unitstep = n>=0;
                            x3 = A.*unitstep;
                            for k=1: length(x3)
                                x_1r(k)=x3(length(x3)-k+1);
                            end
                            stem(app.Grafico,n,x3,'red');
                            hold(app.Grafico);
                            if(app.cbx1.Value==1)
                                stem(app.Grafico,n_1,x_1r,'black');
                            end
                            if(app.cbx2.Value==1)
                                stem(app.Grafico,n_1,-x_1r,'blue');
                            end
                            if(app.cbx3.Value==1)
                                xe=(1/2)*(x3+x_1r);
                                stem(app.Grafico,n,xe,'yellow');
                            end
                            if(app.cbx4.Value==1)
                                xo=(1/2)*(x3-x_1r);
                                stem(app.Grafico,n,xo,'green');
                            end
                            if(app.cbx4.Value==1&&app.cbx3.Value==1)
                                xa=xe+xo;
                                stem(app.Grafico,n,xa,'cyan');
                            end
                            Ex = sum(abs(x3).^2);
                            Px = Ex/length(x3);
                            if Ex < Inf && Px == 0
                                app.Lamp1.Enable='on';
                            elseif Ex == Inf && Px > 0
                                app.Lamp2.Enable='on';
                            else
                                app.Lamp3.Enable = 'on';
                            end
                             %operaciones
                            if (app.aLabel.Visible=='on')
                                opera=app.operacion.Value;
                                switch opera
                                    case 'Escalamiento de amplitud '
                                        a=app.edit7.Value;
                                        xa=a*x3;
                                        stem(app.Grafico,n,xa,'blue');
                                    case 'Suma'
                                        xs=x3+grap2;
                                        stem(app.Grafico,n,xs,'blue');
                                        stem(app.Grafico,n,grap2,'magenta');
                                    case 'Multiplicación '
                                        xs=x3.*grap2;
                                        stem(app.Grafico,n,xs,'blue');
                                        stem(app.Grafico,n,grap2,'magenta');
                                    case 'Diferenciación Metodo aproximado'
                                        xd=diff(x3);
                                        %t(:,1:length(yt))
                                        stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                                    case 'Integración '
                                        y = cumsum(x3) .* (n(2) - n(1));
                                        stem(app.Grafico,n,y,'blue');
                                    case 'Escalamiento de tiempo '
                                        m=app.edit7.Value;
                                        xe=A*heaviside(n*m);
                                        stem(app.Grafico,n,xe,'blue');
                                    case 'Corrimiento en tiempo '
                                        re=app.edit7.Value;
                                        xr=A*heaviside(n+re);
                                        stem(app.Grafico,n+re,xr,'blue');
                                    case 'CONVOLUCIÓN'
                                        xs=conv(x3,grap2);
                                        stem(app.Grafico,grap2,'magenta');
                                        stem(app.Grafico,n,x3,'red');
                                        stem(app.Grafico,xs,'blue');
                                    case 'Correlaccion'
                                        xs=xcorr(x3,grap2,'none');
                                        stem(app.Grafico,x3,'red');
                                        stem(app.Grafico,xs,'blue');
                                        stem(app.Grafico,grap2,'magenta');
                                end
                            end
                            
                    end
                case 'Generación de una señal Impulso'
                    A=app.edit1.Value;
                    n=app.edit2.Value:app.edit3.Value;
                    %A=1;%Amplitud 
                    %n=0:5;%TiempodeSimulación
                    x1 = A.*[zeros(1,3),ones(1,1),zeros(1,2)];
                    x2=(n-3)==0;
                    n_2=-app.edit3.Value:-app.edit2.Value;
                    for k=1: length(x1)
                        x_1r(k)=x1(length(x1)-k+1);
                    end
                    stem(app.UIAxes,x1,'red');
                    hold(app.UIAxes);
                    if(app.cbx1.Value==1)
                        stem(app.UIAxes,n_2,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.UIAxes,n_2,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x1+x_1r);
                        stem(app.UIAxes,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x1-x_1r);
                        stem(app.UIAxes,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.UIAxes,n,xa,'cyan');
                    end
                    Ex = sum(abs(x1).^2);
                    Px = Ex/length(x1);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    
                    for v=1: length(x2)
                        x_2r(v)=x2(length(x2)-v+1);
                    end
                    stem(app.UIAxes_2,n,x2,'red');
                    hold(app.UIAxes_2);
                    if(app.cbx1.Value==1)
                        stem(app.UIAxes_2,n_2,x_2r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.UIAxes_2,n_2,-x_2r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x2+x_2r);
                        stem(app.UIAxes_2,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x2-x_2r);
                        stem(app.UIAxes_2,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.UIAxes_2,n,xa,'cyan');
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa1=a*x1;
                                xa2=a*x2;
                                stem(app.UIAxes,n,xa1,'blue');
                                stem(app.UIAxes_2,n,xa2,'blue');
                            case 'Suma'
                                xa1=x1+grap2;
                                xa2=x2+grap3;
                                stem(app.UIAxes,n,xa1,'blue');
                                stem(app.UIAxes_2,n,xa2,'blue');
                            case 'Multiplicación '
                                xa1=x1.*grap2;
                                xa2=x2.*grap3;
                                stem(app.UIAxes,n,xa1,'blue');
                                stem(app.UIAxes_2,n,xa2,'blue');
                            case 'Diferenciación Metodo aproximado'
                                xd1=diff(x1);
                                xd2=diff(x2);
                                %t(:,1:length(yt))
                                stem(app.UIAxes,n(:,1:length(xd1)),xd1,'blue');
                                stem(app.UIAxes_2,n(:,1:length(xd2)),xd2,'blue');
                            case 'Integración '
                                y1 = cumsum(x1) .* (n(2) - n(1));
                                y2 = cumsum(x2) .* (n(2) - n(1));
                                stem(app.UIAxes,n,y1,'blue');
                                stem(app.UIAxes_2,n,y2,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe1 = (A).*[zeros(1,3),ones(1,1),zeros(1,2)];
                                xe2=(n-3)==0;
                                stem(app.UIAxes,n,xe1,'blue');
                                stem(app.UIAxes_2,n,xe2,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xe1=exp(n+re);
                                xe2 = r.^(-n+re);
                                stem(app.UIAxes,n,xe1,'blue');
                                stem(app.UIAxes_2,n,xe2,'blue');
                            case 'CONVOLUCIÓN'
                                xa1=conv(x1,grap2);
                                if(grap3~=0)
                                    xa2=conv(x2,grap3);
                                else
                                    xa2=conv(x2,grap2);
                                end
                                stem(app.UIAxes,xa1,'blue');
                                stem(app.UIAxes_2,xa2,'blue');
                            case 'Correlaccion'
                                xs1=xcorr(x1,grap2,'none');
                                if(grap3~=0)
                                    xs2=xcorr(x2,grap3,'none');
                                else
                                    xs2=xcorr(x2,grap2,'none');
                                end
                                stem(app.UIAxes,xs1,'blue');
                                stem(app.UIAxes_2,xs2,'blue');
                        end
                    end
                case 'Generación de una señal Rampa'
                    %error
                    A= app.edit1.Value;
                    n1=app.edit2.Value:app.edit3.Value/2;
                    n2=app.edit3.Value/2:app.edit3.Value;
                    r=n1;
                    n=[n1,n2];
                    x1 = A.*[zeros(1,length(n1)),r];
                    for k=1: length(x1)
                        x_1r(k)=x1(length(x1)-k+1);
                    end
                    stem(app.Grafico,n,x1,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        stem(app.Grafico,n,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.Grafico,n,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x1+x_1r);
                        stem(app.Grafico,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x1-x_1r);
                        stem(app.Grafico,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.Grafico,n,xa,'cyan');
                    end
                    Ex = sum(abs(x1).^2);
                    Px = Ex/length(x1);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                     %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x1;
                                stem(app.Grafico,n,xa,'blue');
                            case 'Suma'
                                xs=x1+grap2;
                                stem(app.Grafico,n,xs,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x1.*grap2;
                                stem(app.Grafico,n,xm,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x1);
                                stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                y = cumsum(x1) .* (n(2) - n(1));
                                stem(app.Grafico,n,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A.*[zeros(1,length(n1*m)),r];
                                stem(app.Grafico,n,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A.*[zeros(1,length(n1+re)),r];
                                stem(app.Grafico,n+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x1,grap2);
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x1,grap2,'none');
                                stem(app.Grafico,x1,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                        end
                    end

                    
                case 'Generación de una señal Rectangular'
                    A=app.edit1.Value;
                    n=app.edit2.Value:0.1:app.edit3.Value;
                    n_2=-app.edit3.Value:0.1:-app.edit2.Value;
                    x=A.*rectpuls(n);
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    stem(app.Grafico,n,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        stem(app.Grafico,n_2,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.Grafico,n_2,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        stem(app.Grafico,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        stem(app.Grafico,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.Grafico,n,xa,'cyan');
                    end
                    Ex = sum(abs(x).^2);
                    Px = Ex/length(x);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                stem(app.Grafico,n,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                stem(app.Grafico,n,xs,'blue');
                                stem(app.Grafico,n,grap2,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                stem(app.Grafico,n,xm,'blue');
                                stem(app.Grafico,n,xb,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x);
                                %t(:,1:length(yt))
                                stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                %posible solucion
                                y = cumsum(x) .* (n(2) - n(1));
                                stem(app.Grafico,n,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A.*rectpuls(n*m);
                                stem(app.Grafico,n,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A.*rectpuls(n+re);
                                stem(app.Grafico,n+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2);
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                stem(app.Grafico,x,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                        end
                    end
                case 'Generación de una señal Triangular'
                    A=app.edit1.Value;
                    n=app.edit2.Value:0.1:app.edit3.Value;
                    x = A*tripuls(n);
                    n_2=-app.edit3.Value:0.1:-app.edit2.Value;
                    for k=1: length(x)
                        x_1r(k)=x(length(x)-k+1);
                    end
                    stem(app.Grafico,n,x,'red');
                    hold(app.Grafico);
                    if(app.cbx1.Value==1)
                        stem(app.Grafico,n_2,x_1r,'black');
                    end
                    if(app.cbx2.Value==1)
                        stem(app.Grafico,n_2,-x_1r,'blue');
                    end
                    if(app.cbx3.Value==1)
                        xe=(1/2)*(x+x_1r);
                        stem(app.Grafico,n,xe,'yellow');
                    end
                    if(app.cbx4.Value==1)
                        xo=(1/2)*(x-x_1r);
                        stem(app.Grafico,n,xo,'green');
                    end
                    if(app.cbx4.Value==1&&app.cbx3.Value==1)
                        xa=xe+xo;
                        stem(app.Grafico,n,xa,'cyan');
                    end
                    Ex = sum(abs(x).^2);
                    Px = Ex/length(x);
                    if Ex < Inf && Px == 0
                        app.Lamp1.Enable='on';
                    elseif Ex == Inf && Px > 0
                        app.Lamp2.Enable='on';
                    else
                        app.Lamp3.Enable = 'on';
                    end
                    %operaciones
                    if (app.aLabel.Visible=='on')
                        opera=app.operacion.Value;
                        switch opera
                            case 'Escalamiento de amplitud '
                                a=app.edit7.Value;
                                xa=a*x;
                                stem(app.Grafico,n,xa,'blue');
                            case 'Suma'
                                xs=x+grap2;
                                stem(app.Grafico,n,xs,'blue');
                                stem(app.Grafico,n,xa,'magenta');
                            case 'Multiplicación '
                                xm=x.*grap2;
                                stem(app.Grafico,n,xm,'blue');
                                stem(app.Grafico,n,xb,'magenta');
                            case 'Diferenciación Metodo aproximado'
                                xd=diff(x);
                                %t(:,1:length(yt))
                                stem(app.Grafico,n(:,1:length(xd)),xd,'blue');
                            case 'Integración '
                                %posible solucion
                                y = cumsum(x) .* (n(2) - n(1));
                                stem(app.Grafico,n,y,'blue');
                            case 'Escalamiento de tiempo '
                                m=app.edit7.Value;
                                xe=A*tripuls(n*m);
                                stem(app.Grafico,n,xe,'blue');
                            case 'Corrimiento en tiempo '
                                re=app.edit7.Value;
                                xr=A*tripuls(n+re);
                                stem(app.Grafico,n+re,xr,'blue');
                            case 'CONVOLUCIÓN'
                                xs=conv(x,grap2);
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                            case 'Correlaccion'
                                xs=xcorr(x,grap2,'none');
                                stem(app.Grafico,x,'red');
                                stem(app.Grafico,xs,'blue');
                                stem(app.Grafico,grap2,'magenta');
                        end
                    end
                    
                otherwise
            end
        end

        % Value changed function: operacion
        function operacionValueChanged(app, event)
            value = app.operacion.Value;
            switch value
                case 'Selecionar opcion'
                    app.lisTipo_2.Visible='off';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                    app.aLabel.Visible= 'off';
                    app.aLabel.Text = '';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                    
                    
                case 'Escalamiento de amplitud '
                    app.lisTipo_2.Visible='off';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                    app.aLabel.Visible= 'on';
                    app.aLabel.Text = 'Escalamiento';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'on';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';                    
                case 'Suma'
                    app.lisTipo_2.Visible='on';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                
                case 'Multiplicación '
                    app.lisTipo_2.Visible='on';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                    label=app.Label_2.Text;
                     if (app.edit1.Visible=='on')
                        app.edit7.Visible= 'on';
                        app.aLabel.Visible= 'on';
                        app.aLabel.Text = app.Label.Text;
                        switch label
                            case 'min Tiempo'
                            case 'max Tiempo'
                                app.edit7.Visible= 'off';
                                app.aLabel.Text = 'Opcion no disponible';
                            otherwise
                                app.edit8.Visible= 'on';
                                app.bLabel.Visible= 'on';
                                app.bLabel.Text = app.Label_2.Text;
                                app.edit9.Visible= 'on';
                                app.dLabel.Visible= 'on';
                                app.dLabel.Text = app.Label_3.Text;
                        end
                     end
                case 'Diferenciación Metodo aproximado'
                    app.lisTipo_2.Visible='off';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                    app.aLabel.Visible= 'on';
                    app.aLabel.Text = 'Selecionar graficar';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Integración '
                    app.lisTipo_2.Visible='off';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                    app.aLabel.Visible= 'on';
                    app.aLabel.Text = 'Selecionar graficar';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'off';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Escalamiento de tiempo '
                    app.lisTipo_2.Visible='off';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                    app.aLabel.Visible= 'on';
                    app.aLabel.Text = 'Escaramiento';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'on';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'Corrimiento en tiempo '
                    app.lisTipo_2.Visible='off';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                    app.aLabel.Visible= 'on';
                    app.aLabel.Text = 'Movimiento';
                    app.bLabel.Visible= 'off';
                    app.bLabel.Text = '';
                    app.dLabel.Visible= 'off';
                    app.dLabel.Text = '';
                    app.edit7.Visible= 'on';
                    app.edit8.Visible= 'off';
                    app.edit9.Visible= 'off';
                case 'CONVOLUCIÓN'
                    app.lisTipo_2.Visible='on';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
                case 'Correlaccion'
                    app.lisTipo_2.Visible='on';
                    if(app.Grafico.Visible=='on')
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'on';
                        app.UIAxes.Visible= 'off';
                        app.UIAxes_2.Visible= 'off';
                    else
                        cla(app.UIAxes,'reset');
                        cla(app.Grafico,'reset');
                        cla(app.UIAxes_2,'reset');
                        app.Grafico.Visible= 'off';
                        app.UIAxes.Visible= 'on';
                        app.UIAxes_2.Visible= 'on';
                    end
            end
            
        end

        % Value changed function: lisTipo_2
        function lisTipo_2ValueChanged(app, event)
            val2 = app.lisTipo_2.Value;
                switch val2
                    case 'Señal Senoidal'
                            app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Amplitud';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Frecuencia';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Ciclo';
                            app.edit10.Visible= 'on';
                            app.fLabel.Visible= 'on';
                            app.fLabel.Text = 'Tiempo Min';
                            app.edit11.Visible= 'on';
                            app.eLabel.Visible= 'on';
                            app.eLabel.Text = 'Tiempo Max';
                    case 'Señal Exponencial Compleja'
                           app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Tiempo max';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Tiempo min';
                            app.edit9.Visible= 'off';
                            app.dLabel.Visible= 'off';
                            app.edit10.Visible= 'off';
                            app.fLabel.Visible= 'off';
                            app.edit11.Visible= 'off';
                            app.eLabel.Visible= 'off';
                            app.opciografica_2.Visible= 'on';
                            app.opciografica_2.Items = {'Seleciopnar opcion', 'Cálculo de la parte Real de x(t)', 'Cálculo de la parte imaginaria de x(t)', 'Cálculo de la magnitud de x(t)','Cálculo del Argumento de x(t)'};
                    case 'Señal Cuadrada'
                            app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Amplitud';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Frecuencia';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Ciclo';
                            app.edit10.Visible= 'on';
                            app.fLabel.Visible= 'on';
                            app.fLabel.Text = 'Tiempo Min';
                            app.edit11.Visible= 'on';
                            app.eLabel.Visible= 'on';
                            app.eLabel.Text = 'Tiempo Max';
                    case 'Diente de Sierra'
                           app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Amplitud';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Frecuencia';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Ciclo';
                            app.edit10.Visible= 'on';
                            app.fLabel.Visible= 'on';
                            app.fLabel.Text = 'Tiempo Min';
                            app.edit11.Visible= 'on';
                            app.eLabel.Visible= 'on';
                            app.eLabel.Text = 'Tiempo Max';
                    case 'Exponencial Crecientey Decreciente'
                            app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Base';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Tiempo Max';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Tiempo Min';
                            app.edit10.Visible= 'off';
                            app.fLabel.Visible= 'off';
                            app.edit11.Visible= 'off';
                            app.eLabel.Visible= 'off';
                    case 'Generación de una señal Rampa'
                            app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Base';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Tiempo Max';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Tiempo Min';
                            app.edit10.Visible= 'off';
                            app.fLabel.Visible= 'off';
                            app.edit11.Visible= 'off';
                            app.eLabel.Visible= 'off';
                    case 'Generación de una señal Escalón'
                            app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Base';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Tiempo Max';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Tiempo Min';
                            app.edit10.Visible= 'off';
                            app.fLabel.Visible= 'off';
                            app.edit11.Visible= 'off';
                            app.eLabel.Visible= 'off';
                    case 'Generación de una señal Impulso'
                            app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Base';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Tiempo Max';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Tiempo Min';
                            app.edit10.Visible= 'off';
                            app.fLabel.Visible= 'off';
                            app.edit11.Visible= 'off';
                            app.eLabel.Visible= 'off';
                    case 'Generación de una señal Triangular'
                            app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Base';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Tiempo Max';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Tiempo Min';
                            app.edit10.Visible= 'off';
                            app.fLabel.Visible= 'off';
                            app.edit11.Visible= 'off';
                            app.eLabel.Visible= 'off';
                    case 'Generación de una señal Rectangular'
                            app.edit7.Visible= 'on';
                            app.aLabel.Visible= 'on';
                            app.aLabel.Text = 'Base';
                            app.edit8.Visible= 'on';
                            app.bLabel.Visible= 'on';
                            app.bLabel.Text = 'Tiempo Max';
                            app.edit9.Visible= 'on';
                            app.dLabel.Visible= 'on';
                            app.dLabel.Text = 'Tiempo Min';
                            app.edit10.Visible= 'off';
                            app.fLabel.Visible= 'off';
                            app.edit11.Visible= 'off';
                            app.eLabel.Visible= 'off';
                    otherwise
                        app.opciografica_2.Visible= 'of';
                        app.edit7.Visible= 'off';
                        app.aLabel.Visible= 'off';
                        app.edit8.Visible= 'off';
                        app.bLabel.Visible= 'off';
                        app.edit9.Visible= 'off';
                        app.dLabel.Visible= 'off';
                        app.edit10.Visible= 'off';
                        app.fLabel.Visible= 'off';
                        app.edit11.Visible= 'off';
                        app.eLabel.Visible= 'off';
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lbltitulo = uilabel(app.UIFigure);
            app.lbltitulo.Text = 'Practica 2 Señales Elementales';
            app.TipodegraficaDropDownLabel = uilabel(app.UIFigure);
            app.TipodegraficaDropDownLabel.Text = 'Tipo de grafica';
            app.lisTipo = uidropdown(app.UIFigure);
            app.lisTipo.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'};
            app.lisTipo.ValueChangedFcn = createCallbackFcn(app, @lisTipoValueChanged, true);
            app.lisTipo.Value = 'Seleciopnar opcion';
            app.btngrafico1 = uibutton(app.UIFigure, 'push');
            app.btngrafico1.ButtonPushedFcn = createCallbackFcn(app, @btngrafico1Pushed, true);
            app.btngrafico1.Enable = 'off';
            app.btngrafico1.Visible = 'off';
            app.btngrafico1.Text = {'Graficar'; ''};
            app.TiempodelagraficaDropDownLabel = uilabel(app.UIFigure);
            app.TiempodelagraficaDropDownLabel.Text = 'Tiempo de la grafica';
            app.listiempo = uidropdown(app.UIFigure);
            app.listiempo.Items = {'Tiempo discreto', 'Tiempo continuo'};
            app.listiempo.ValueChangedFcn = createCallbackFcn(app, @listiempoValueChanged, true);
            app.listiempo.Value = 'Tiempo discreto';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.edit1 = uieditfield(app.UIFigure, 'numeric');
            app.edit1.Visible = 'off';
            app.edit2 = uieditfield(app.UIFigure, 'numeric');
            app.edit2.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '';
            app.edit3 = uieditfield(app.UIFigure, 'numeric');
            app.edit3.Visible = 'off';
            app.btngrafico2 = uibutton(app.UIFigure, 'push');
            app.btngrafico2.ButtonPushedFcn = createCallbackFcn(app, @btngrafico2ButtonPushed, true);
            app.btngrafico2.Text = 'Graficar';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '';
            app.edit4 = uieditfield(app.UIFigure, 'numeric');
            app.edit4.Visible = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '';
            app.edit5 = uieditfield(app.UIFigure, 'numeric');
            app.edit5.Visible = 'off';
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciadeMuestreoEditFieldLabel.Visible = 'off';
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.edit6 = uieditfield(app.UIFigure, 'numeric');
            app.edit6.Visible = 'off';
            app.PartedelagraficaDropDownLabel = uilabel(app.UIFigure);
            app.PartedelagraficaDropDownLabel.Visible = 'off';
            app.PartedelagraficaDropDownLabel.Text = 'Parte de la grafica';
            app.opciografica = uidropdown(app.UIFigure);
            app.opciografica.Items = {};
            app.opciografica.Visible = 'off';
            app.opciografica.Value = {};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.FuncionoriginallLabel = uilabel(app.UIFigure);
            app.FuncionoriginallLabel.Text = 'Funcion originall';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '';
            app.cbx1 = uicheckbox(app.UIFigure);
            app.cbx1.Text = 'Funcion par y(n)=x(-n)';
            app.cbx2 = uicheckbox(app.UIFigure);
            app.cbx2.Text = 'Funcion impar y(n)=-x(n)';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '';
            app.cbx4 = uicheckbox(app.UIFigure);
            app.cbx4.Text = 'Funcion Xo(t-n)';
            app.cbx3 = uicheckbox(app.UIFigure);
            app.cbx3.Text = 'Funcion Xe(t-n)';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '';
            app.SumadeXoyXeLabel = uilabel(app.UIFigure);
            app.SumadeXoyXeLabel.Text = 'Suma de Xo y Xe';
            app.EnergiaLampLabel = uilabel(app.UIFigure);
            app.EnergiaLampLabel.Text = 'Energia';
            app.Lamp1 = uilamp(app.UIFigure);
            app.Lamp1.Enable = 'off';
            app.PotencialLampLabel = uilabel(app.UIFigure);
            app.PotencialLampLabel.Text = 'Potencial';
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp2.Enable = 'off';
            app.NingunaLampLabel = uilabel(app.UIFigure);
            app.NingunaLampLabel.Text = 'Ninguna';
            app.Lamp3 = uilamp(app.UIFigure);
            app.Lamp3.Enable = 'off';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '';
            app.TipodesealSwitchLabel = uilabel(app.UIFigure);
            app.TipodesealSwitchLabel.Text = 'Tipo de señal';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Periodica', 'Aperiodica'};
            app.Switch.Value = 'Periodica';
            app.OperacionesBsicasSobrelaSealDropDownLabel = uilabel(app.UIFigure);
            app.OperacionesBsicasSobrelaSealDropDownLabel.Text = 'Operaciones Básicas Sobre la Señal';
            app.operacion = uidropdown(app.UIFigure);
            app.operacion.Items = {'Selecionar opcion', 'Escalamiento de amplitud ', 'Suma', 'Multiplicación ', 'Diferenciación Metodo aproximado', 'Integración ', 'Escalamiento de tiempo ', 'Corrimiento en tiempo ', 'CONVOLUCIÓN', 'Correlaccion'};
            app.operacion.ValueChangedFcn = createCallbackFcn(app, @operacionValueChanged, true);
            app.operacion.Value = 'Selecionar opcion';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '';
            app.FunciondelaoperacionLabel = uilabel(app.UIFigure);
            app.FunciondelaoperacionLabel.Text = 'Funcion de la operacion';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Visible = 'off';
            app.aLabel.Text = 'a';
            app.edit7 = uieditfield(app.UIFigure, 'numeric');
            app.edit7.Visible = 'off';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Visible = 'off';
            app.bLabel.Text = 'b';
            app.edit8 = uieditfield(app.UIFigure, 'numeric');
            app.edit8.Visible = 'off';
            app.dLabel = uilabel(app.UIFigure);
            app.dLabel.Visible = 'off';
            app.dLabel.Text = 'd';
            app.edit9 = uieditfield(app.UIFigure, 'numeric');
            app.edit9.Visible = 'off';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '';
            app.FuncionExtraLabel = uilabel(app.UIFigure);
            app.FuncionExtraLabel.Text = 'Funcion Extra';
            app.TipodegraficaDropDownLabel_2 = uilabel(app.UIFigure);
            app.TipodegraficaDropDownLabel_2.Visible = 'off';
            app.TipodegraficaDropDownLabel_2.Text = 'Tipo de grafica';
            app.lisTipo_2 = uidropdown(app.UIFigure);
            app.lisTipo_2.Items = {'Seleciopnar opcion', 'Señal Cuadrada', 'Diente de Sierra', 'Señal Senoidal', 'Exponencial Crecientey Decreciente', 'Señal Exponencial Compleja', 'Generación de una señal Escalón', 'Generación de una señal Impulso', 'Generación de una señal Rampa', 'Generación de una señal Rectangular', 'Generación de una señal Triangular'};
            app.lisTipo_2.ValueChangedFcn = createCallbackFcn(app, @lisTipo_2ValueChanged, true);
            app.lisTipo_2.Visible = 'off';
            app.lisTipo_2.Value = 'Seleciopnar opcion';
            app.eLabel = uilabel(app.UIFigure);
            app.eLabel.Visible = 'off';
            app.eLabel.Text = 'e';
            app.edit10 = uieditfield(app.UIFigure, 'numeric');
            app.edit10.Visible = 'off';
            app.fLabel = uilabel(app.UIFigure);
            app.fLabel.Visible = 'off';
            app.fLabel.Text = 'f';
            app.edit11 = uieditfield(app.UIFigure, 'numeric');
            app.edit11.Visible = 'off';
            app.PartedelagraficaDropDownLabel_2 = uilabel(app.UIFigure);
            app.PartedelagraficaDropDownLabel_2.Visible = 'off';
            app.PartedelagraficaDropDownLabel_2.Text = 'Parte de la grafica';
            app.opciografica_2 = uidropdown(app.UIFigure);
            app.opciografica_2.Items = {};
            app.opciografica_2.Visible = 'off';
            app.opciografica_2.Value = {};
            app.Grafico = uiaxes(app.UIFigure);
            title(app.Grafico, {'Grafica'; ''})
            xlabel(app.Grafico, 'Tiempo')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            app.Grafico.Visible = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Grafica'; ''})
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, {'Grafica'; ''})
            app.UIAxes_2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end