classdef Practica4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextArea                     matlab.ui.control.TextArea
        ConfiguracinLabel            matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        GrficaListBoxLabel           matlab.ui.control.Label
        GrficaListBox                matlab.ui.control.ListBox
        TipoListBoxLabel             matlab.ui.control.Label
        TipoListBox                  matlab.ui.control.ListBox
        TextArea_3                   matlab.ui.control.TextArea
        TiempoEditFieldLabel         matlab.ui.control.Label
        TiempoEditField              matlab.ui.control.NumericEditField
        TiempoEditField_2            matlab.ui.control.NumericEditField
        TiempoEditField_3            matlab.ui.control.NumericEditField
        GraficarButton               matlab.ui.control.Button
        ImparCheckBox                matlab.ui.control.CheckBox
        ParCheckBox                  matlab.ui.control.CheckBox
        GrficasPanel                 matlab.ui.container.Panel
        AmplitudEditFieldLabel       matlab.ui.control.Label
        AmplitudEditField            matlab.ui.control.NumericEditField
        VindependienteDropDownLabel  matlab.ui.control.Label
        VindependienteDropDown       matlab.ui.control.DropDown
        VdependienteDropDownLabel    matlab.ui.control.Label
        VdependienteDropDown         matlab.ui.control.DropDown
        Grfica2ListBoxLabel          matlab.ui.control.Label
        Grfica2ListBox               matlab.ui.control.ListBox
        EscalarEditFieldLabel        matlab.ui.control.Label
        EscalarEditField             matlab.ui.control.NumericEditField
        Tiempo2EditFieldLabel        matlab.ui.control.Label
        Tiempo2EditField             matlab.ui.control.NumericEditField
        Tiempo2EditField_2           matlab.ui.control.NumericEditField
        Tiempo2EditField_3           matlab.ui.control.NumericEditField
        AmplitudEditField_2          matlab.ui.control.NumericEditField
        F1Label                      matlab.ui.control.Label
        F2Label                      matlab.ui.control.Label
        IzquierdaCheckBox            matlab.ui.control.CheckBox
        DerechaCheckBox              matlab.ui.control.CheckBox
        ComprimirCheckBox            matlab.ui.control.CheckBox
        ExpandirCheckBox             matlab.ui.control.CheckBox
        CorrimientoEditFieldLabel    matlab.ui.control.Label
        CorrimientoEditField         matlab.ui.control.NumericEditField
        Amplitud_newEditFieldLabel   matlab.ui.control.Label
        Amplitud_newEditField        matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            % Recibimos los parámetros
            t0 = app.TiempoEditField.Value;
            t1 = app.TiempoEditField_2.Value;
            t2 = app.TiempoEditField_3.Value;
            T0= app.Tiempo2EditField.Value;
            T1= app.Tiempo2EditField_2.Value;
            T2= app.Tiempo2EditField_3.Value;
            A = app.AmplitudEditField.Value;
            A2= app.AmplitudEditField_2.Value;
            senial = app.GrficaListBox.Value;
            senial2 = app.Grfica2ListBox.Value;
            % Señales a graficar
            switch senial
                
                case 'Senoidal'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        f = 100;
                        phi = pi/2;
                        t = t0:t1:t2;
                        grap = A*sin(2*pi*t*f+phi);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Senoidal Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        f = 0.125;
                        phi = pi/2;
                        t = t0:t1:t2;
                        grap = A*cos(2*pi*t*f-phi);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Senoidal Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        f = 1;
                        phi = pi/2;
                        t = t0:t1:t2;
                        grap = A*sin(2*pi*t*f+phi);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Senoidal Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        f = 0.125;
                        phi = pi/2;
                        t = t0:t1:t2;
                        grap = A*cos(2*pi*t*f-phi);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Senoidal Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        f = 1;
                        phi = pi/2;
                        t = t0:t1:t2;
                        grap = A*sin(2*pi*t*f+phi);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Senoidal Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        f = 0.125;
                        phi = pi/2;
                        t = t0:t1:t2;
                        grap = A*cos(2*pi*t*f-phi);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,impar,'m');
                        title(ax1,'Senoidal Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        f = 100;
                        phi = pi/2;
                        t = t0:t1:t2;
                        grap = A*sin(2*pi*t*f+phi);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap);
                        title(ax1,'Senoidal Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else 
                        f = 0.125;
                        phi = pi/2;
                        t = t0:t1:t2;
                        grap = A*cos(2*pi*t*f-phi);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap);
                        title(ax1,'Senoidal Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end
                    
                case 'Exponencial'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = exp(t);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Exponencial continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = exp(t);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Exponencial discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = exp(t);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Exponencial continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = exp(t);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Exponencial discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = exp(t);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Exponencial continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = exp(t);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Exponencial discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        t = t0:t1:t2;
                        grap = exp(t);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap);
                        title(ax1,'Exponencial Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else
                        t = t0:t1:t2;
                        grap = exp(t);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap);
                        title(ax1,'Exponencial Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end    
                case 'Cuadrada'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*square(2*pi*t);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Cuadrada continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*square(2*pi*t);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Cuadrada discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*square(2*pi*t);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Cuadrada continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*square(2*pi*t);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Cuadrada discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*square(2*pi*t);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Cuadrada continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*square(2*pi*t);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Cuadrada discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        t = t0:t1:t2;
                        grap = A*square(2*pi*t);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap);
                        title(ax1,'Cuadrada Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else
                        t = t0:t1:t2;
                        grap = A*square(2*pi*t);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap);
                        title(ax1,'Cuadrada Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end
                    
                case 'Diente de sierra'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*sawtooth(2*pi*t,1/2);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Diente de sierra continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*sawtooth(2*pi*t,1/2);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Diente de sierra discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*sawtooth(2*pi*t,1/2);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Diente de sierra continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*sawtooth(2*pi*t,1/2);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Diente de sierra discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*sawtooth(2*pi*t,1/2);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Diente de sierra continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*sawtooth(2*pi*t,1/2);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Diente de sierra discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        t = t0:t1:t2;
                        grap = A*sawtooth(2*pi*t,1/2);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap);
                        title(ax1,'Diente de sierra Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else
                        t = t0:t1:t2;
                        grap = A*sawtooth(2*pi*t,1/2);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap);
                        title(ax1,'Diente de sierra Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end
                    
                case 'Exponencial inversa'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = 1.5.^(-t);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Exponencial inversa continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = 1.5.^(-t);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Exponencial inversa discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = 1.5.^(-t);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Exponencial inversa continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = 1.5.^(-t);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Exponencial inversa discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = 1.5.^(-t);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Exponencial inversa continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar, 'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = 1.5.^(-t);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Exponencial inversa discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        t = t0:t1:t2;
                        grap = 1.5.^(-t);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap);
                        title(ax1,'Exponencial inversa Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else
                        t = t0:t1:t2;
                        grap = 1.5.^(-t);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap);
                        title(ax1,'Exponencial inversa Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end
                    
                case 'Rampa'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A.*uramp(t-2);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Rampa continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A.*uramp(t-2);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Rampa discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A.*uramp(t-2);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Rampa continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A.*uramp(t-2);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Rampa discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A.*uramp(t-2);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Rampa continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A.*uramp(t-2);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Rampa discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        t = t0:t1:t2;
                        grap = A.*uramp(t-2);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap);
                        title(ax1,'Rampa Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else
                        t = t0:t1:t2;
                        grap = A.*uramp(t-2);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap);
                        title(ax1,'Rampa Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end
                    
                case 'Escalon'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*heaviside(t);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Escalon continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*heaviside(t);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Escalon discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*heaviside(t);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Escalon continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*heaviside(t);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Escalon discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*heaviside(t);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Escalon continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = A*heaviside(t);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Escalon discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        t = t0:t1:t2;
                        grap = A*heaviside(t);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap);
                        title(ax1,'Escalon Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else
                        t = t0:t1:t2;
                        grap = A*heaviside(t);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap);
                        title(ax1,'Escalon Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end
                    
                case 'Pulso triangular'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = tripuls(t,4);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Pulso triangular continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = tripuls(t,4);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Pulso triangular discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = tripuls(t,4);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Pulso triangular continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = tripuls(t,4);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Pulso triangular discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = tripuls(t,4);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Pulso triangular continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = tripuls(t,4);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Pulso triangular discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        t = t0:t1:t2;
                        grap = tripuls(t,4);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,grap);
                        title(ax1,'Pulso triangular Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else
                        t = t0:t1:t2;
                        grap = tripuls(t,4);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,grap);
                        title(ax1,'Pulso triangular Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end
                    
                case 'Pulso rectangular'
                    if ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = rectpuls(t,4);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Pulso rectangular continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        plot(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = rectpuls(t,4);
                        par = (grap + flip(grap)) / 2;
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        ax3 = subplot(2, 2, 4, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Pulso rectangular discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                        stem(ax3,t,impar,'g');
                        title(ax3,'Componente impar');
                        xlabel(ax3,'Tiempo');
                        ylabel(ax3,'Amplitud');
                        grid(ax3,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = rectpuls(t,4);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Pulso rectangular continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ParCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = rectpuls(t,4);
                        par = (grap + flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Pulso rectangular discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,par,'r');
                        title(ax2,'Componente par');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Continua")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = rectpuls(t,4);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        plot(ax1,t,grap,'m');
                        title(ax1,'Pulso rectangular continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        plot(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif ((strcmp(app.TipoListBox.Value,"Discreta")) && (app.ImparCheckBox.Value == 1))
                        t = t0:t1:t2;
                        grap = rectpuls(t,4);
                        impar = (grap - flip(grap)) / 2;
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(2, 2, 1, 'Parent', app.GrficasPanel);
                        ax2 = subplot(2, 2, 2, 'Parent', app.GrficasPanel);
                        stem(ax1,t,grap,'m');
                        title(ax1,'Pulso rectangular discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                        stem(ax2,t,impar,'g');
                        title(ax2,'Componente impar');
                        xlabel(ax2,'Tiempo');
                        ylabel(ax2,'Amplitud');
                        grid(ax2,'on');
                    elseif strcmp(app.TipoListBox.Value,"Continua")
                        t = t0:t1:t2;
                        grap = rectpuls(t,4);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        plot(ax1,grap);
                        title(ax1,'Pulso rectangular Continua');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    else
                        t = t0:t1:t2;
                        grap = rectpuls(t,4);
                        app.GrficasPanel.AutoResizeChildren = 'off';
                        ax1 = subplot(1, 1, 1, 'Parent', app.GrficasPanel);
                        stem(ax1,grap);
                        title(ax1,'Pulso rectangular Discreta');
                        xlabel(ax1,'Tiempo');
                        ylabel(ax1,'Amplitud');
                        grid(ax1,'on');
                    end
                otherwise
                   exit
            end
         
         switch senial2
                case 'Senoidal'
                        f = 0.125;
                        phi = pi/2;
                        T = T0:T1:T2;
                        grap2 = A2*cos(2*pi*T*f-phi);
                case 'Exponencial'
                        T = T0:T1:T2;
                        grap2 = exp(T);
                case 'Cuadrada'
                        T = T0:T1:T2;
                        grap2 = A2*square(2*pi*T);
                case 'Diente de sierra'
                        T = T0:T1:T2;
                        grap2 = A2*sawtooth(2*pi*T,1/2);
                case 'Exponencial inversa'
                        T = T0:T1:T2;
                        grap2 = 1.5.^(-T);
                case 'Rampa'
                        T = T0:T1:T2;
                        grap2 = A2.*uramp(T-2);
                case 'Escalon'
                        T = T0:T1:T2;
                        grap2 = A2*heaviside(T);
                case 'Pulso triangular'
                        T = T0:T1:T2;
                        grap2 = tripuls(T,4); 
                case 'Pulso rectangular'
                        T = T0:T1:T2;
                        grap2 = rectpuls(T,4);
         end
%             
%Operaciones sobre las señales
%
                switch app.VindependienteDropDown.Value
                    case 'Escalamiento'
                            E= app.EscalarEditField.Value;
                            if(app.ComprimirCheckBox.Value==1)
                            t_new= t./E;
                            elseif(app.ExpandirCheckBox.Value==1)
                            t_new= t.*E;
                            end
                            %tE=t*app.EscalarEditField.Value;
                            %grap_E= resample(grap,tE,t);
                            %t_new = linspace(t(1), t(end), length(t)*E); % Nuevo vector de tiempo
                            %grap_E = interp1(t, grap, t_new, 'linear'); % Escalamiento en el tiempo
                            plot(t,grap)
                            hold on
                            plot(t_new,grap);
                    case 'Reflexion'
                        if (strcmp(app.VindependienteDropDown.Value, 'Reflexion')==1)
                            %grap_rev= fliplr(grap);
                            %plot(t,grap_rev);
                            tR=-t;
                            %grap_rev=flip(grap);
                            plot(tR,grap)
                            grid on;
                            %hold on
                        end
                    case 'Corrimiento'
                        P= app.CorrimientoEditField.Value;
                        if(app.DerechaCheckBox.Value==1)
                            %tD= t-P;
                            %x_pad = padarray(grap, [0 P], 'pre');
                            grap_c= circshift(grap, [0 P]);
                            %grap_c= circshift(x_pad, [0 P]);
                            plot(t, grap, 'b', t, grap_c, 'r--'), grid on;
                        elseif(app.IzquierdaCheckBox.Value==1)
                            grap_c= circshift(grap, [0 -P]);
                            plot(t, grap, 'b', t, grap_c, 'r--'), grid on;
                            %plot(tI, grap), grid on
                        end
                end
            switch app.VdependienteDropDown.Value
                case 'Suma'
                        if ((strcmp(senial,'Senoidal')) && strcmp(senial2, 'Exponencial')==1)
A1 = 1; % amplitud
f1 = 1; % frecuencia
phi1 = pi/4; % fase
t = linspace(0, 2*pi, 100); % vector de tiempo
x1 = A1*sin(2*pi*f1*t + phi1); % señal senoidal
                        A2 = 0.5; % amplitud
                        tau = 0.5; % constante de tiemp o
                        x2 = A2*exp(-t/tau); % señal exponencial
                        y= x1+x2;
                        subplot(1,1,2);
                        plot(t, y);
                        else
                    Suma_funciones= grap + grap2;
                    plot(t, Suma_funciones), grid on
                        end
                case 'Multiplicacion'
                    if ((strcmp(senial,'Senoidal')) && strcmp(senial2, 'Exponencial')==1)
t = linspace(0, 10, 1000); % Vector de tiempo
A1 = 1; % Amplitud de la señal senoidal
f1 = 2; % Frecuencia de la señal senoidal
phi1 = pi/4; % Fase de la señal senoidal
s1 = A1 * sin(2*pi*f1*t + phi1); % Señal senoidal

A2 = 2; % Amplitud de la señal exponencial
tau = 1; % Constante de tiempo de la señal exponencial
s2 = A2 * exp(-t/tau); % Señal exponencial
s3 = s1 .* s2; % Multiplicación de la señal senoidal con la exponencial
                    plot(s3), grid on
                    else
                       grap_m= grap.*grap2;
                       plot(t, grap_m,"g")
                    end
                case 'Diferenciacion'
                    salida = diff(grap);
                    plot(t, grap,"b")
                    hold on
                    plot(t(1:end-1),salida,"m");
                    %plot (salida,"m"), grid on
                case 'Integracion'
                    %x_suma= grap+grap2; 
                    %integral= trapz(t,x_suma)
                    %plot(t, x_suma, 'LineWidth', 2);
                    %hold on;
                    %plot([t(1), t(end)], [integral, integral], '--r', 'LineWidth', 2); 
                    
                    %f=@(grap)
                    %F= integral(f, t0, t2)
                    
                    %int_1= cumsum(grap);
                    %int_2= cumsum(grap2);
                    Suma= grap+grap2;
                    grap_I= cumsum(Suma);
                    subplot(2,1,1)
                    plot(t, Suma,"b")
                    hold on
                    subplot(2,1,2)
                    plot(t,grap_I)
                    
                case 'Escalamiento'
                    escala=app.Amplitud_newEditField.Value
                    grap_e= escala * grap;
                    grap_e2= escala * grap2;
                    plot(t, grap)
                    hold on
                    plot(t, grap_e)
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.ConfiguracinLabel = uilabel(app.UIFigure);
            app.ConfiguracinLabel.Text = 'Configuración';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.GrficaListBoxLabel = uilabel(app.UIFigure);
            app.GrficaListBoxLabel.Text = 'Gráfica';
            app.GrficaListBox = uilistbox(app.UIFigure);
            app.GrficaListBox.Items = {'Senoidal', 'Exponencial', 'Exponencial inversa', 'Cuadrada', 'Diente de sierra', 'Rampa', 'Escalon', 'Pulso triangular', 'Pulso rectangular'};
            app.GrficaListBox.Value = {};
            app.TipoListBoxLabel = uilabel(app.UIFigure);
            app.TipoListBoxLabel.Text = 'Tipo';
            app.TipoListBox = uilistbox(app.UIFigure);
            app.TipoListBox.Items = {'Continua', 'Discreta'};
            app.TipoListBox.Value = {};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TiempoEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiempoEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TiempoEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Icon = 'Imagen1.jpg';
            app.GraficarButton.Text = 'Graficar';
            app.ImparCheckBox = uicheckbox(app.UIFigure);
            app.ImparCheckBox.Text = 'Impar';
            app.ParCheckBox = uicheckbox(app.UIFigure);
            app.ParCheckBox.Text = 'Par';
            app.GrficasPanel = uipanel(app.UIFigure);
            app.GrficasPanel.Title = 'Gráficas';
            app.AmplitudEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.UIFigure, 'numeric');
            app.VindependienteDropDownLabel = uilabel(app.UIFigure);
            app.VindependienteDropDownLabel.Text = 'V. independiente';
            app.VindependienteDropDown = uidropdown(app.UIFigure);
            app.VindependienteDropDown.Items = {'None', 'Escalamiento', 'Reflexion', 'Corrimiento'};
            app.VindependienteDropDown.Value = 'None';
            app.VdependienteDropDownLabel = uilabel(app.UIFigure);
            app.VdependienteDropDownLabel.Text = 'V. dependiente';
            app.VdependienteDropDown = uidropdown(app.UIFigure);
            app.VdependienteDropDown.Items = {'None', 'Escalamiento', 'Suma', 'Multiplicacion', 'Diferenciacion', 'Integracion'};
            app.VdependienteDropDown.Value = 'None';
            app.Grfica2ListBoxLabel = uilabel(app.UIFigure);
            app.Grfica2ListBoxLabel.Text = 'Gráfica2';
            app.Grfica2ListBox = uilistbox(app.UIFigure);
            app.Grfica2ListBox.Items = {'Senoidal', 'Exponencial', 'Exponencial inversa', 'Cuadrada', 'Diente de sierra', 'Rampa', 'Escalon', 'Pulso triangular', 'Pulso rectangular'};
            app.Grfica2ListBox.Value = {};
            app.EscalarEditFieldLabel = uilabel(app.UIFigure);
            app.EscalarEditFieldLabel.Text = 'Escalar';
            app.EscalarEditField = uieditfield(app.UIFigure, 'numeric');
            app.Tiempo2EditFieldLabel = uilabel(app.UIFigure);
            app.Tiempo2EditFieldLabel.Text = 'Tiempo2';
            app.Tiempo2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Tiempo2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Tiempo2EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.F1Label = uilabel(app.UIFigure);
            app.F1Label.Text = 'F1';
            app.F2Label = uilabel(app.UIFigure);
            app.F2Label.Text = 'F2';
            app.IzquierdaCheckBox = uicheckbox(app.UIFigure);
            app.IzquierdaCheckBox.Text = 'Izquierda';
            app.DerechaCheckBox = uicheckbox(app.UIFigure);
            app.DerechaCheckBox.Text = 'Derecha';
            app.ComprimirCheckBox = uicheckbox(app.UIFigure);
            app.ComprimirCheckBox.Text = 'Comprimir';
            app.ExpandirCheckBox = uicheckbox(app.UIFigure);
            app.ExpandirCheckBox.Text = 'Expandir';
            app.CorrimientoEditFieldLabel = uilabel(app.UIFigure);
            app.CorrimientoEditFieldLabel.Text = 'Corrimiento';
            app.CorrimientoEditField = uieditfield(app.UIFigure, 'numeric');
            app.Amplitud_newEditFieldLabel = uilabel(app.UIFigure);
            app.Amplitud_newEditFieldLabel.Text = 'Amplitud_new';
            app.Amplitud_newEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Practica4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end